classdef DataManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UtilitairedesauvegardeetchargementdesdonnestraitesLabel  matlab.ui.control.Label
        DonnesdelapplicationListBoxLabel  matlab.ui.control.Label
        AppDataListBox               matlab.ui.control.ListBox
        DonnesdufichierListBoxLabel  matlab.ui.control.Label
        FileDataListBox              matlab.ui.control.ListBox
        LocalLoadButton              matlab.ui.control.Button
        LoadButton                   matlab.ui.control.Button
        SaveButton                   matlab.ui.control.Button
        SaveAllButton                matlab.ui.control.Button
        LoadAllButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        inputList % liste des variables venant de l'appli principale
        filepath = '';
        filename = '';
        
        Acqui;
        Black;
        White;
        Ref;
        AcqTimes;
        CorrectedCube;
        TrueColorsCube;
        SpectralRef;
        SpectralWavlgt;
        MetaData;
        QMatrix;
        QMatrix_color;
        StateOfCorrection;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data)
            app.inputList = {};
            i = 1;
            if (sum(data.Acqui,'all') ~= 0)
                app.Acqui = data.Acqui;
                app.inputList{i} = 'Acqui';
                i = i+1;
            end
            if (sum(data.Black,'all') ~= 0)
                app.Black = data.Black;
                app.inputList{i} = 'Black';
                i = i+1;
            end
            if (sum(data.White,'all') ~= 0)
                app.White = data.White;
                app.inputList{i} = 'White';
                i = i+1;
            end
            if (sum(data.Ref,'all') ~= 0)
                app.Ref = data.Ref;
                app.inputList{i} = 'Ref';
                i = i+1;
            end
            if (sum(data.AcqTimes,'all') ~= 0)
                app.AcqTimes = data.AcqTimes;
                app.inputList{i} = 'AcqTimes';
                i = i+1;
            end
            if (sum(data.CorrectedCube,'all') ~= 0)
                app.CorrectedCube = data.CorrectedCube;
                app.inputList{i} = 'CorrectedCube';
                i = i+1;
            end
            if (sum(data.TrueColorsCube,'all') ~= 0)
                app.TrueColorsCube = data.TrueColorsCube;
                app.inputList{i} = 'TrueColorsCube';
                i = i+1;
            end
            if (~isempty(data.SpectralRef))
                app.SpectralRef = data.SpectralRef;
                app.inputList{i} = 'SpectralRef';
                i = i+1;
            end
            if (~isempty(data.SpectralWavlgt))
                app.SpectralWavlgt = data.SpectralWavlgt;
                app.inputList{i} = 'SpectralWavlgt';
                i = i+1;
            end
            if (~isempty(data.MetaData))
                app.MetaData = data.MetaData;
                app.inputList{i} = 'MetaData';
                i = i+1;
            end
            if (~isempty(data.QMatrix))
                app.QMatrix = data.QMatrix;
                app.inputList{i} = 'QMatrix';
                i = i+1;
            end
            if (~isempty(data.QMatrix_color))
                app.QMatrix_color = data.QMatrix_color;
                app.inputList{i} = 'QMatrix_color';
            end
            app.StateOfCorrection = data.StateOfCorrection;
            app.AppDataListBox.Items = app.inputList;
            app.FileDataListBox.Items = {};
            assignin("base",'loadFlag',0);
        end

        % Button pushed function: LocalLoadButton
        function LocalLoadButtonPushed(app, event)
            answ = uiconfirm(app.UIFigure,'Souhaitez-vous charger un fichier pour la sauvegarde ou l''import de données?','But du chargement de fichier',"Options",{'Sauvegarde','Import','Retour'},"DefaultOption",'Retour',"CancelOption",'Retour','Icon','question');
            if strcmp(answ,'Import')
                [app.filename, app.filepath] = uigetfile([userpath '/' '*.mat']);
                temp = whos('-file',[app.filepath '/' app.filename]);
                temp = {temp.name};
                matched = matches(temp,'StateOfCorrection');
                app.FileDataListBox.Items = temp(~matched);                
                app.FileDataListBox.Enable = 'on';
                app.LoadButton.Enable = 'on';
                app.LoadAllButton.Enable = 'on';
                app.AppDataListBox.Enable = 'off';
                app.SaveButton.Enable = 'off';
                app.SaveAllButton.Enable = 'off';
            elseif strcmp(answ,'Sauvegarde')
                [app.filename, app.filepath] = uiputfile([userpath '/' '*.mat']);
                temp = whos('-file',[app.filepath '/' app.filename]);
                app.FileDataListBox.Items = {temp.name};
            else
                return;
            end
        end

        % Button pushed function: SaveAllButton, SaveButton
        function SaveButtonPushed(app, event)
            if strcmp(event.Source.Tag,'SaveAll')
                app.AppDataListBox.Value = app.AppDataListBox.Items;
            end           
            matched = matches(app.FileDataListBox.Items,app.AppDataListBox.Value);
            if sum(matched)~=0
                answ = uiconfirm(app.UIFigure,'Êtes-vous sûr de vouloir écraser des données du fichier ouvert ?','Écrasement de données ?',"Options",{'Oui','Non'},"CancelOption",'Non',"DefaultOption",'Non',"Icon","warning");
            end
            if sum(matched)==0 || strcmp(answ,'Oui')
                if strcmp(app.filename,'')
                    [app.filename, app.filepath] = uiputfile([userpath '/' '*.mat']);
                end
                
                for i = 1 : length(app.AppDataListBox.Value)
                    eval([app.AppDataListBox.Value{i} ' = app.' app.AppDataListBox.Value{i} ';']);
                end
                StateOfCorrection = app.StateOfCorrection;
                
                if isfile([app.filepath '/' app.filename])
                    save([app.filepath '/' app.filename],app.AppDataListBox.Value{:},'StateOfCorrection','-append');
                else
                    save([app.filepath '/' app.filename],app.AppDataListBox.Value{:},'StateOfCorrection');
                end
                delete(app.UIFigure);
            end
        end

        % Button pushed function: LoadAllButton, LoadButton
        function LoadButtonPushed(app, event)
            if strcmp(event.Source.Tag,'ImportAll')
                app.FileDataListBox.Value = app.FileDataListBox.Items;
            end
            matched = matches(app.AppDataListBox.Items,app.FileDataListBox.Value);
            if sum(matched)~=0
                answ = uiconfirm(app.UIFigure,'Êtes-vous sûr de vouloir écraser des données de l''application ?','Écrasement de données ?',"Options",{'Oui','Non'},"CancelOption",'Non',"DefaultOption",'Non',"Icon","warning");
            end
            if sum(matched)==0 || strcmp(answ,'Oui')
                assignin("base",'filepath',[app.filepath '/' app.filename]);
                assignin("base",'variableToLoad', app.FileDataListBox.Value);
                assignin("base",'loadFlag',1);
            end
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UtilitairedesauvegardeetchargementdesdonnestraitesLabel = uilabel(app.UIFigure);
            app.UtilitairedesauvegardeetchargementdesdonnestraitesLabel.Text = 'Utilitaire de sauvegarde et chargement des données traitées';
            app.DonnesdelapplicationListBoxLabel = uilabel(app.UIFigure);
            app.DonnesdelapplicationListBoxLabel.Text = 'Données de l''application';
            app.AppDataListBox = uilistbox(app.UIFigure);
            app.AppDataListBox.Items = {'Acqui', 'Black', 'White', 'Ref', 'AcqTimes', 'CorrectedCube', 'TrueColorsCube', 'SpectralRef', 'SpectralWavlgt', 'MetaData', 'StateOfCorrection'};
            app.AppDataListBox.Multiselect = 'on';
            app.AppDataListBox.Value = {'Black'};
            app.DonnesdufichierListBoxLabel = uilabel(app.UIFigure);
            app.DonnesdufichierListBoxLabel.Text = 'Données du fichier';
            app.FileDataListBox = uilistbox(app.UIFigure);
            app.FileDataListBox.Items = {};
            app.FileDataListBox.Multiselect = 'on';
            app.FileDataListBox.Enable = 'off';
            app.FileDataListBox.Value = {};
            app.LocalLoadButton = uibutton(app.UIFigure, 'push');
            app.LocalLoadButton.ButtonPushedFcn = createCallbackFcn(app, @LocalLoadButtonPushed, true);
            app.LocalLoadButton.Text = 'Charger un fichier de données dans l''utilitaire';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Enable = 'off';
            app.LoadButton.Text = 'Importer';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Sauvegarder';
            app.SaveAllButton = uibutton(app.UIFigure, 'push');
            app.SaveAllButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveAllButton.Tag = 'SaveAll';
            app.SaveAllButton.Text = 'Sauvegarder tout';
            app.LoadAllButton = uibutton(app.UIFigure, 'push');
            app.LoadAllButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadAllButton.Tag = 'ImportAll';
            app.LoadAllButton.Enable = 'off';
            app.LoadAllButton.Text = 'Importer tout';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataManager(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end