classdef DisplayICA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AnalyseencomposantesindpendantesLabel  matlab.ui.control.Label
        RenderingParametersPanel      matlab.ui.container.Panel
        IcVisuPanel                   matlab.ui.container.Panel
        nICSpinnerLabel               matlab.ui.control.Label
        nICSpinner                    matlab.ui.control.Spinner
        OppositeIC                    matlab.ui.control.Button
        RGBVisuPanel                  matlab.ui.container.Panel
        CanalRSpinnerLabel            matlab.ui.control.Label
        CanalRSpinner                 matlab.ui.control.Spinner
        CanalGSpinnerLabel            matlab.ui.control.Label
        CanalGSpinner                 matlab.ui.control.Spinner
        CanalBSpinnerLabel            matlab.ui.control.Label
        CanalBSpinner                 matlab.ui.control.Spinner
        Switch                        matlab.ui.control.Switch
        ParamtresdecalculPanel        matlab.ui.container.Panel
        MthodededcorrlationDropDownLabel  matlab.ui.control.Label
        DecorrMethodDropDown          matlab.ui.control.DropDown
        NbICsSpinnerLabel             matlab.ui.control.Label
        NbICsSpinner                  matlab.ui.control.Spinner
        FonctionNLDropDownLabel       matlab.ui.control.Label
        NlFunctionDropDown            matlab.ui.control.DropDown
        FinetuningDropDownLabel       matlab.ui.control.Label
        NlFinetuneDropDown            matlab.ui.control.DropDown
        ParamtragedeNLEditFieldLabel  matlab.ui.control.Label
        NlParameterNumericField       matlab.ui.control.NumericEditField
        PasSpinnerLabel               matlab.ui.control.Label
        StepSpinner                   matlab.ui.control.Spinner
        StabButton                    matlab.ui.control.StateButton
        PcaBasedButton                matlab.ui.control.StateButton
        FastICAButton                 matlab.ui.control.Button
        RedoButton                    matlab.ui.control.Button
        ExportICAButton               matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        originalCube (1040, 1392, 15) double; % conteneur de la matrice des données multispectrales originales (corrigées)
        pcaCube (1040, 1392, 15) double; % conteneur de la matrice des données issues de la PCA
        pcaCoeff; % conteneur des coeffs de la pca
        pcaVar; % conteneur des variances de la pca
        
        nbIcsComputed = 3;
        icaCube; % conteneur de la matrice des composantes indépendantes
        A; % conteneur de la matrice de séparation W
        W; % conteneur de la matrice de mélange A
    end
    
    methods (Access = private)
        
        function majDisplay(app)
            if strcmp(app.Switch.Value,'IC seule')
                imshow(app.icaCube(:,:,app.nICSpinner.Value),'Parent',app.UIAxes);
            else
                compactedCube(:,:,1)=app.icaCube(:,:,app.CanalRSpinner.Value);
                compactedCube(:,:,2)=app.icaCube(:,:,app.CanalGSpinner.Value);
                compactedCube(:,:,3)=app.icaCube(:,:,app.CanalBSpinner.Value);
                imshow(compactedCube,'Parent',app.UIAxes);
            end
        end
    end
    
    methods (Static)        
        [Out1, Out2, Out3] = fastica(mixedsig, varargin);
        [A, W] = fpica(X, whiteningMatrix, dewhiteningMatrix, approach, ...
			numOfIC, g, finetune, a1, a2, myy, stabilization, ...
			epsilon, maxNumIterations, maxFinetune, initState, ...
			guess, sampleSize, displayMode, displayInterval, ...
			s_verbose);
        [newVectors, whiteningMatrix, dewhiteningMatrix] = whitenv(vectors, E, D, s_verbose);
        [newVectors, meanValue] = remmean(vectors);
        [E, D] = pcamat(vectors, firstEig, lastEig, s_interactive, s_verbose)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, originalCube, pcaCube, pcaCoeff, pcaVar)
            app.originalCube = originalCube;
            app.pcaCube = pcaCube;
            app.pcaCoeff = pcaCoeff;
            app.pcaVar = diag(pcaVar);
            
        end

        % Value changed function: NlFunctionDropDown
        function NlFunctionDropDownValueChanged(app, event)
            value = app.NlFunctionDropDown.Value;
            if strcmp(value,'tanh')
                app.NlParameterNumericField.Enable = 'on';
                app.NlParameterNumericField.Limits = [-Inf, Inf];
                app.NlParameterNumericField.Value = 1;
            elseif strcmp(value,'gauss')
                app.NlParameterNumericField.Enable = 'on';
                app.NlParameterNumericField.Limits = [1e-6, Inf];
                app.NlParameterNumericField.Value = 1;
            else
                app.NlParameterNumericField.Enable = 'off';
                app.NlParameterNumericField.Value = nan;
            end
        end

        % Value changed function: StepSpinner
        function StepSpinnerValueChanged(app, event)
            value = app.StepSpinner.Value;
            if value~=0
                app.StabButton.Value = 1;
            end
        end

        % Value changed function: NbICsSpinner
        function NbICsSpinnerValueChanged(app, event)
            value = app.NbICsSpinner.Value;
            app.nbIcsComputed = value;
            app.nICSpinner.Limits(2) = value;
            app.CanalRSpinner.Limits(2) = value;
            app.CanalGSpinner.Limits(2) = value;
            app.CanalBSpinner.Limits(2) = value;
        end

        % Button pushed function: FastICAButton
        function FastICAButtonPushed(app, event)
            if app.PcaBasedButton==1
                workingCube = app.pcaCube;
            else
                workingCube = app.originalCube;
            end
            
            if strcmp(app.NlFunctionDropDown.Value,'tanh') || strcmp(app.NlFinetuneDropDown.Value,'tanh')
                a1 = app.NlParameterNumericField.Value;
                a2 = 1;
            elseif strcmp(app.NlFunctionDropDown.Value,'gauss') || strcmp(app.NlFinetuneDropDown.Value,'gauss')
                a1 = 1;
                a2 = app.NlParameterNumericField.Value;
            else
                a1 = 1;
                a2 = 1;
            end
            
            if app.StabButton.Value == 1
                stabval = 'on';
            else
                stabval = 'off';
            end
            
            reshapedCube = reshape(workingCube,[size(workingCube,1)*size(workingCube,2),size(workingCube,3)]);
            [app.icaCube, app.A, app.W] = MultispectralViewerPkg.DisplayICA.fastica(reshapedCube','approach', app.DecorrMethodDropDown.Value, 'numOfIc', app.nbIcsComputed, 'g', app.NlFunctionDropDown.Value, 'finetune', app.NlFinetuneDropDown.Value, 'a1', a1, 'a2', a2, 'mu', app.StepSpinner.Value, 'stabilization', stabval);% 'pcaE', app.pcaCoeff, 'pcaD', app.pcaVar);
            standardizedICACube = (app.icaCube - min(app.icaCube,[],2))./(max(app.icaCube,[],2) - min(app.icaCube,[],2));
            app.icaCube = reshape(standardizedICACube',[size(workingCube,1),size(workingCube,2),app.nbIcsComputed]);            
            
            app.RedoButton.Enable = 'on';
            app.RenderingParametersPanel.Enable = 'on';
            app.ExportICAButton.Enable ='on';
            app.majDisplay();
            
        end

        % Button pushed function: RedoButton
        function RedoButtonPushed(app, event)
            if app.PcaBasedButton==1
                workingCube = app.pcaCube;
            else
                workingCube = app.originalCube;
            end
            
            if strcmp(app.NlFunctionDropDown.Value,'tanh') || strcmp(app.NlFinetuneDropDown.Value,'tanh')
                a1 = app.NlParameterNumericField.Value;
                a2 = 1;
            elseif strcmp(app.NlFunctionDropDown.Value,'gauss') || strcmp(app.NlFinetuneDropDown.Value,'gauss')
                a1 = 1;
                a2 = app.NlParameterNumericField.Value;
            else
                a1 = 1;
                a2 = 1;
            end
            
            if app.StabButton.Value == 1
                stabval = 'on';
            else
                stabval = 'off';
            end
            
            reshapedCube = reshape(workingCube,[size(workingCube,1)*size(workingCube,2),size(workingCube,3)]);
            reshapedIca = reshape(app.icaCube,[size(app.icaCube,1)*size(app.icaCube,2),size(app.icaCube,3)]);
            [app.icaCube, app.A, app.W] = MultispectralViewerPkg.DisplayICA.fastica(reshapedCube','approach', app.DecorrMethodDropDown.Value, 'numOfIc', app.nbIcsComputed, 'g', app.NlFunctionDropDown.Value, 'finetune', app.NlFinetuneDropDown.Value, 'a1', a1, 'a2', a2, 'mu', app.StepSpinner.Value, 'stabilization', stabval,'initGuess', reshapedIca);%, 'pcaE', app.pcaCoeff, 'pcaD', app.pcaVar, 'initGuess', reshapedIca);
            standardizedICACube = (app.icaCube - min(app.icaCube,[],2))./(max(app.icaCube,[],2) - min(app.icaCube,[],2));
            app.icaCube = reshape(standardizedICACube',[size(workingCube,1),size(workingCube,2),app.nbIcsComputed]);            
            
            app.majDisplay();
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value,'IC seule')
                app.IcVisuPanel.Enable = 'on';
                app.RGBVisuPanel.Enable = 'off';                
            else
                app.IcVisuPanel.Enable = 'off';
                app.RGBVisuPanel.Enable = 'on';  
            end
            app.majDisplay();
        end

        % Value changed function: CanalBSpinner, CanalGSpinner, 
        % CanalRSpinner, nICSpinner
        function VisuSpinnerValueChanged(app, event)
            app.majDisplay();            
        end

        % Button pushed function: OppositeIC
        function OppositeICButtonPushed(app, event)
            app.icaCube(:,:,app.nICSpinner.Value) = 1-app.icaCube(:,:,app.nICSpinner.Value);
            app.majDisplay();
        end

        % Button pushed function: ExportICAButton
        function ExportICAButtonPushed(app, event)
            param.decorrMeth = app.DecorrMethodDropDown.Value;
            param.NLfun = app.NlFunctionDropDown.Value;
            param.NLfinetune = app.NlFinetuneDropDown.Value;
            param.a = app.NlParameterNumericField.Value;
            param.mu = app.StepSpinner.Value;
            param.stab = app.StabButton.Value;
            param.basedOnPca = app.PcaBasedButton.Value;
            icaCube = app.icaCube;
            varsToSave = {'icaCube','param'};
            uisave(varsToSave);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AnalyseencomposantesindpendantesLabel = uilabel(app.UIFigure);
            app.AnalyseencomposantesindpendantesLabel.Text = 'Analyse en composantes indépendantes';
            app.RenderingParametersPanel = uipanel(app.UIFigure);
            app.RenderingParametersPanel.Enable = 'off';
            app.RenderingParametersPanel.Title = 'Paramétrage du rendu';
            app.IcVisuPanel = uipanel(app.RenderingParametersPanel);
            app.IcVisuPanel.Title = 'Visualisation composante seule';
            app.nICSpinnerLabel = uilabel(app.IcVisuPanel);
            app.nICSpinnerLabel.Text = 'n° IC';
            app.nICSpinner = uispinner(app.IcVisuPanel);
            app.nICSpinner.Limits = [1 15];
            app.nICSpinner.RoundFractionalValues = 'on';
            app.nICSpinner.ValueChangedFcn = createCallbackFcn(app, @VisuSpinnerValueChanged, true);
            app.nICSpinner.Value = 1;
            app.OppositeIC = uibutton(app.IcVisuPanel, 'push');
            app.OppositeIC.ButtonPushedFcn = createCallbackFcn(app, @OppositeICButtonPushed, true);
            app.OppositeIC.Text = 'Application d''un facteur ''-1''';
            app.RGBVisuPanel = uipanel(app.RenderingParametersPanel);
            app.RGBVisuPanel.Enable = 'off';
            app.RGBVisuPanel.Title = 'Visualisation RGB';
            app.CanalRSpinnerLabel = uilabel(app.RGBVisuPanel);
            app.CanalRSpinnerLabel.Text = 'Canal R';
            app.CanalRSpinner = uispinner(app.RGBVisuPanel);
            app.CanalRSpinner.Limits = [1 15];
            app.CanalRSpinner.ValueChangedFcn = createCallbackFcn(app, @VisuSpinnerValueChanged, true);
            app.CanalRSpinner.Value = 1;
            app.CanalGSpinnerLabel = uilabel(app.RGBVisuPanel);
            app.CanalGSpinnerLabel.Text = 'Canal G';
            app.CanalGSpinner = uispinner(app.RGBVisuPanel);
            app.CanalGSpinner.Limits = [1 15];
            app.CanalGSpinner.ValueChangedFcn = createCallbackFcn(app, @VisuSpinnerValueChanged, true);
            app.CanalGSpinner.Value = 1;
            app.CanalBSpinnerLabel = uilabel(app.RGBVisuPanel);
            app.CanalBSpinnerLabel.Text = 'Canal B';
            app.CanalBSpinner = uispinner(app.RGBVisuPanel);
            app.CanalBSpinner.Limits = [1 15];
            app.CanalBSpinner.ValueChangedFcn = createCallbackFcn(app, @VisuSpinnerValueChanged, true);
            app.CanalBSpinner.Value = 1;
            app.Switch = uiswitch(app.RenderingParametersPanel, 'slider');
            app.Switch.Items = {'IC seule', 'RGB'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'IC seule';
            app.ParamtresdecalculPanel = uipanel(app.UIFigure);
            app.ParamtresdecalculPanel.Title = 'Paramètres de calcul';
            app.MthodededcorrlationDropDownLabel = uilabel(app.ParamtresdecalculPanel);
            app.MthodededcorrlationDropDownLabel.Text = 'Méthode de décorrélation';
            app.DecorrMethodDropDown = uidropdown(app.ParamtresdecalculPanel);
            app.DecorrMethodDropDown.Items = {'symm', 'defl'};
            app.DecorrMethodDropDown.Value = 'defl';
            app.NbICsSpinnerLabel = uilabel(app.ParamtresdecalculPanel);
            app.NbICsSpinnerLabel.Text = 'Nb ICs';
            app.NbICsSpinner = uispinner(app.ParamtresdecalculPanel);
            app.NbICsSpinner.Limits = [0 15];
            app.NbICsSpinner.ValueChangedFcn = createCallbackFcn(app, @NbICsSpinnerValueChanged, true);
            app.NbICsSpinner.Value = 3;
            app.FonctionNLDropDownLabel = uilabel(app.ParamtresdecalculPanel);
            app.FonctionNLDropDownLabel.Text = 'Fonction NL';
            app.NlFunctionDropDown = uidropdown(app.ParamtresdecalculPanel);
            app.NlFunctionDropDown.Items = {'pow3', 'tanh', 'gauss', 'skew'};
            app.NlFunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @NlFunctionDropDownValueChanged, true);
            app.NlFunctionDropDown.Value = 'pow3';
            app.FinetuningDropDownLabel = uilabel(app.ParamtresdecalculPanel);
            app.FinetuningDropDownLabel.Text = '"Fine-tuning"';
            app.NlFinetuneDropDown = uidropdown(app.ParamtresdecalculPanel);
            app.NlFinetuneDropDown.Items = {'pow3', 'tanh', 'gauss', 'skew', 'off'};
            app.NlFinetuneDropDown.Value = 'off';
            app.ParamtragedeNLEditFieldLabel = uilabel(app.ParamtresdecalculPanel);
            app.ParamtragedeNLEditFieldLabel.Enable = 'off';
            app.ParamtragedeNLEditFieldLabel.Text = 'Paramétrage de NL';
            app.NlParameterNumericField = uieditfield(app.ParamtresdecalculPanel, 'numeric');
            app.NlParameterNumericField.Enable = 'off';
            app.PasSpinnerLabel = uilabel(app.ParamtresdecalculPanel);
            app.PasSpinnerLabel.Text = 'Pas';
            app.StepSpinner = uispinner(app.ParamtresdecalculPanel);
            app.StepSpinner.Limits = [1e-06 Inf];
            app.StepSpinner.ValueChangedFcn = createCallbackFcn(app, @StepSpinnerValueChanged, true);
            app.StepSpinner.Value = 1;
            app.StabButton = uibutton(app.ParamtresdecalculPanel, 'state');
            app.StabButton.Text = 'Stab';
            app.PcaBasedButton = uibutton(app.ParamtresdecalculPanel, 'state');
            app.PcaBasedButton.Text = 'Data = PCA';
            app.FastICAButton = uibutton(app.UIFigure, 'push');
            app.FastICAButton.ButtonPushedFcn = createCallbackFcn(app, @FastICAButtonPushed, true);
            app.FastICAButton.Text = 'Calcul FastICA';
            app.RedoButton = uibutton(app.UIFigure, 'push');
            app.RedoButton.ButtonPushedFcn = createCallbackFcn(app, @RedoButtonPushed, true);
            app.RedoButton.Enable = 'off';
            app.RedoButton.Text = 'Refaire';
            app.ExportICAButton = uibutton(app.UIFigure, 'push');
            app.ExportICAButton.ButtonPushedFcn = createCallbackFcn(app, @ExportICAButtonPushed, true);
            app.ExportICAButton.Enable = 'off';
            app.ExportICAButton.Text = 'Export ICA';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DisplayICA(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end