classdef HasimotoEnhancement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        HasimotoscolorenhancementLabel  matlab.ui.control.Label
        ParametersPanel                 matlab.ui.container.Panel
        NbdePCspourlestimationSpinnerLabel  matlab.ui.control.Label
        nbPCsSpinner                    matlab.ui.control.Spinner
        BandespectralerhausserSpinnerLabel  matlab.ui.control.Label
        BandToEnhanceSpinner            matlab.ui.control.Spinner
        ValeurdamplificationEditFieldLabel  matlab.ui.control.Label
        kEditField                      matlab.ui.control.NumericEditField
        RenderingColorButton            matlab.ui.control.Button
        BackColorButton                 matlab.ui.control.Button
        RenderingButton                 matlab.ui.control.Button
        ReNormStateButton               matlab.ui.control.StateButton
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        dataCube % conteneur des données issues de l'application principale, doit contenir uniquement les filtres réels, pas de données non filtrées
        QMatrixColor % conteneur de la matrice de passage spectre/RGB
        pixH % nb de pixel sur la hauteur de l'image
        pixW % nb de pixel sur la largeur de l'image
        nbFilters % nb de filtres
        RgbRendering % rendu en couleurs RGB
        
        renderingColor % couleur utilisée pour réhaussement du canal choisi
        backColor % couleur du fond
        % attention ces deux couleurs sont tranformées en observations spec
        
        S % conteneur de l'estimation spectrale
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dataCube, QMatrixColor)
            app.dataCube = dataCube;
            app.QMatrixColor = QMatrixColor;
            app.pixH = size(dataCube,1);
            app.pixW = size(dataCube,2);
            app.nbFilters = size(dataCube,3);
            app.RgbRendering = zeros(app.pixH,app.pixW,3);
            
            app.nbPCsSpinner.Limits = [1, app.nbFilters];
            app.BandToEnhanceSpinner.Limits(2) = app.nbFilters;
            
            for i = 1 : app.pixH
                for j = 1 : app.pixW
                    app.RgbRendering(i,j,:) = app.QMatrixColor * squeeze(app.dataCube(i,j,:));
                end
            end
            imshow(app.RgbRendering,'Parent',app.UIAxes);
        end

        % Button pushed function: RenderingColorButton
        function RenderingColorButtonPushed(app, event)
            colorTarget = uisetcolor();
            app.renderingColor = pinv(app.QMatrixColor)*colorTarget';
        end

        % Button pushed function: BackColorButton
        function BackColorButtonPushed(app, event)
            ok = 'Non';
            h = figure();
            while strcmp(ok,'Non')
                imshow(app.RgbRendering);
                [x,y] = ginput(2);
                drawnow();
                hold on;
                rectangle('Position',[x(1) y(1) x(2)-x(1) y(2)-y(1)],'EdgeColor',[0 1 0]);
                ok = questdlg('Êtes-vous satisfait de la zone selectionnée pour le calcul de la couleur de fond?','Zone ok?','Oui','Non','Non');
            end
            close(h);
            app.backColor = squeeze(mean(app.dataCube(round(y(1)):round(y(2)),round(x(1)):round(x(2)),:),[1 2]));
        end

        % Button pushed function: RenderingButton
        function RenderingButtonPushed(app, event)
            f = waitbar(0,'Calcul du rendu RGB en cours');
            f.CloseRequestFcn = '';
            if (app.BandToEnhanceSpinner.Value == 0)
                for i = 1 : app.pixH
                    for j = 1 : app.pixW
                        app.RgbRendering(i,j,:) = app.QMatrixColor * squeeze(app.dataCube(i,j,:));
                    end
                    waitbar(1/(app.pixH)*i,f);
                end
            else
                if (isempty(app.renderingColor)||isempty(app.backColor))
                    errordlg('Les couleurs de réhaussement et/ou de fond n''ont pas été sélectionnées.','Erreur sélection couleurs');
                    delete(f);
                    return;
                end
                % Transformation de la forme de la matrice de données
                G = (reshape(app.dataCube,[app.pixH*app.pixW,app.nbFilters]))';
                % Calcul de l'estimation spectrale si besoin (initialisation ou
                % nb de pcs changé)
                if isempty(app.S)
                    % Calcul des composantes principales en gérant la moyenne "à la
                    % main"
                    toPCACube = reshape(app.dataCube,[app.pixH*app.pixW,app.nbFilters]);
                    toPCACube = toPCACube - mean(toPCACube,1);
                    toPCACube = reshape(toPCACube,[app.pixH,app.pixW,app.nbFilters]);
                    [pcaCube,pcaCoeff,~] = hyperpca(toPCACube,app.nbFilters,'Method','Eig','MeanCentered',0);
                    reshapedPCACube = reshape(pcaCube,[app.pixH*app.pixW,app.nbFilters]);
                    reshapedPCACube = reshapedPCACube';
                    % Calcul de l'estimation spectrale
                    nbPCs = app.nbPCsSpinner.Value;
                    G_moy = mean(G,2);
                    U = pcaCoeff(:,1:nbPCs);
                    Alpha = reshapedPCACube(1:nbPCs,:);
                    app.S = zeros(app.nbFilters,app.pixH*app.pixW);
                    for j = 1 : app.pixH*app.pixW
                        somme = 0;
                        for i = 1 : nbPCs
                            somme = somme + Alpha(i,j)*U(:,i);
                        end
                        app.S(:,j) = somme + G_moy;
                    end
                end
                
                % calcul de la matrice de poids en fonction de la bande à
                % réhausser et des paramètres de couleur associés
                
                bandToEnhance = app.BandToEnhanceSpinner.Value;
                k = app.kEditField.Value;
                W = zeros(app.nbFilters,app.nbFilters);
                W(:,bandToEnhance)=k*(rescale(app.renderingColor-app.backColor));
                
                Ge = zeros(app.nbFilters,app.pixH*app.pixW);
                for j = 1 : app.pixH*app.pixW
                    Ge(:,j) = W*abs((G(:,j) - app.S(:,j))) + G(:,j);
                end
                
                if (app.ReNormStateButton.Value == 1)
                    Ge = rescale(Ge);
                elseif ((max(Ge,[],'all')>1)||(min(Ge,[],'all')<0))
                    h=warndlg('Attention, saturation des données traitées');
                    waitfor(h);
                end
                
                Ge = reshape(Ge',[app.pixH,app.pixW,app.nbFilters]);
                
                for i = 1 : app.pixH
                    for j = 1 : app.pixW
                        app.RgbRendering(i,j,:) = app.QMatrixColor * squeeze(Ge(i,j,1:15));
                    end
                    waitbar(1/(app.pixH)*i,f);
                end
            end
            
            waitbar(1,f,'Calcul terminé');
            pause(0.5);
            delete(f);
            
            imshow(app.RgbRendering,'Parent',app.UIAxes);
            drawnow();
        end

        % Value changed function: nbPCsSpinner
        function nbPCsSpinnerValueChanged(app, event)
            app.S = [];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HasimotoscolorenhancementLabel = uilabel(app.UIFigure);
            app.HasimotoscolorenhancementLabel.Text = 'Hasimoto''s color enhancement';
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Réglages';
            app.NbdePCspourlestimationSpinnerLabel = uilabel(app.ParametersPanel);
            app.NbdePCspourlestimationSpinnerLabel.Text = 'Nb de PCs pour l''estimation';
            app.nbPCsSpinner = uispinner(app.ParametersPanel);
            app.nbPCsSpinner.Limits = [1 Inf];
            app.nbPCsSpinner.ValueChangedFcn = createCallbackFcn(app, @nbPCsSpinnerValueChanged, true);
            app.nbPCsSpinner.Tooltip = {'Nombre de composantes principales à garder pour l''estimation spectrale'};
            app.nbPCsSpinner.Value = 1;
            app.BandespectralerhausserSpinnerLabel = uilabel(app.ParametersPanel);
            app.BandespectralerhausserSpinnerLabel.Text = 'Bande spectrale à réhausser';
            app.BandToEnhanceSpinner = uispinner(app.ParametersPanel);
            app.BandToEnhanceSpinner.Limits = [0 Inf];
            app.BandToEnhanceSpinner.Tooltip = {'Bande spectrale à réhausser, si 0 calcul du rendu RGB standard.'};
            app.BandToEnhanceSpinner.Value = 1;
            app.ValeurdamplificationEditFieldLabel = uilabel(app.ParametersPanel);
            app.ValeurdamplificationEditFieldLabel.Text = 'Valeur d''amplification';
            app.kEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.kEditField.Limits = [0 Inf];
            app.kEditField.Value = 1;
            app.RenderingColorButton = uibutton(app.ParametersPanel, 'push');
            app.RenderingColorButton.ButtonPushedFcn = createCallbackFcn(app, @RenderingColorButtonPushed, true);
            app.RenderingColorButton.Text = 'Couleur de rendu';
            app.BackColorButton = uibutton(app.ParametersPanel, 'push');
            app.BackColorButton.ButtonPushedFcn = createCallbackFcn(app, @BackColorButtonPushed, true);
            app.BackColorButton.Text = 'Couleur de fond';
            app.RenderingButton = uibutton(app.ParametersPanel, 'push');
            app.RenderingButton.ButtonPushedFcn = createCallbackFcn(app, @RenderingButtonPushed, true);
            app.RenderingButton.Text = 'Calcul du rendu';
            app.ReNormStateButton = uibutton(app.ParametersPanel, 'state');
            app.ReNormStateButton.Text = 'Renormalisation';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HasimotoEnhancement(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end