classdef MultispectralViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ChargementMenu                 matlab.ui.container.Menu
        AcquisitionsLoad               matlab.ui.container.Menu
        BlackLoad                      matlab.ui.container.Menu
        WhiteLoad                      matlab.ui.container.Menu
        ReferenceLoad                  matlab.ui.container.Menu
        ClearLoaded                    matlab.ui.container.Menu
        CalibrationMenu                matlab.ui.container.Menu
        OffsetCalib                    matlab.ui.container.Menu
        FlatfieldCalib                 matlab.ui.container.Menu
        RegistrationCalib              matlab.ui.container.Menu
        ColorCalib                     matlab.ui.container.Menu
        AdvancedProcessMenu            matlab.ui.container.Menu
        ICA                            matlab.ui.container.Menu
        Hasimoto                       matlab.ui.container.Menu
        Grifoni                        matlab.ui.container.Menu
        AutresMenu                     matlab.ui.container.Menu
        SaveProcessedData              matlab.ui.container.Menu
        DisplayMetadata                matlab.ui.container.Menu
        DisplayHelp                    matlab.ui.container.Menu
        DisplayAbout                   matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        ConfigPanel                    matlab.ui.container.Panel
        DisplayTypePanel               matlab.ui.container.Panel
        ChoosenDisplayType             matlab.ui.container.TabGroup
        GreyScaleTab                   matlab.ui.container.Tab
        NegativeImgButton              matlab.ui.control.Button
        FilterNameDropDown             matlab.ui.control.DropDown
        NomdufiltreDropDownLabel       matlab.ui.control.Label
        FilterBwEditField              matlab.ui.control.NumericEditField
        BandepassantenmEditFieldLabel  matlab.ui.control.Label
        FilterCenterEditField          matlab.ui.control.NumericEditField
        LongeurdondecentralenmEditFieldLabel  matlab.ui.control.Label
        FilterNbSpinner                matlab.ui.control.Spinner
        NumroduclichmultispectralSpinnerLabel  matlab.ui.control.Label
        FalseColorTab                  matlab.ui.container.Tab
        ApplyFalseColorButton          matlab.ui.control.Button
        CanalBDropDown                 matlab.ui.control.DropDown
        CanalBDropDownLabel            matlab.ui.control.Label
        CanalGDropDown                 matlab.ui.control.DropDown
        CanalGDropDownLabel            matlab.ui.control.Label
        CanalRDropDown                 matlab.ui.control.DropDown
        CanalRDropDownLabel            matlab.ui.control.Label
        UserInfoPanel                  matlab.ui.container.Panel
        ColorCalibLamp                 matlab.ui.control.Lamp
        CalibrationcouleurspectreLampLabel  matlab.ui.control.Label
        RegisterCalibLamp              matlab.ui.control.Lamp
        MiseenregistreLampLabel        matlab.ui.control.Label
        FlatfieldCalibLamp             matlab.ui.control.Lamp
        CorrectionflatfieldLampLabel   matlab.ui.control.Label
        OffsetCalibLamp                matlab.ui.control.Lamp
        CorrectiondeloffsetLampLabel   matlab.ui.control.Label
        ConsoleArea                    matlab.ui.control.TextArea
        ProcessPanel                   matlab.ui.container.Panel
        TrueColorsButton               matlab.ui.control.StateButton
        SpectralPointButton            matlab.ui.control.StateButton
        PcaButton                      matlab.ui.control.StateButton
        ContrastStretchingButton       matlab.ui.control.StateButton
        DisplayPanel                   matlab.ui.container.Panel
        ImageRendering                 matlab.ui.control.UIAxes
        ImageDetailAxes                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Constant = true)
        Wavelengths = [425; 465; 480; 500; 525; 550; 565; 580; 600; ...
            615; 630; 640; 665; 675; 690; 0];
        BandWidths = [30; 30; 17; 20; 20; 20; 10; 19; 20; 20; 22; 20; ...
            15; 15; 20; 0];
        FilterNames = {'425AF30'; '465AF30'; '480DF17'; '500DF20'; ...
            '525DF20';'550FB40';'580DF19';'600FB40';'630DF22'; ...
            '650FB10';'665DF15';'675DF15';'700FB40';'750FB40'; ...
            '800FB40';'None'};
        NbFilters = 16; % Nombre de positions de la roue à filtre et donc de canaux, attention le 16 correspond à une prise directe.
        PixelWidth = 1392;
        PixelHeight = 1040;
        
    end
    
    properties (Access = private)
        SpectralFolder; % Conteneur du dossier des fichiers spectraux & RGB
        FilePath = userpath; % Conteneur du chemin d'accès aux images;
        FileNames; % Conteneur du nom des fichiers, en respectant que:
        % FileNames{1}=Acqui; {2}=Black; {3}=White; {4}=Ref;
        Acqui (1040, 1392, 16) double; % Conteneur de la matrice d'image des acquisitions
        Black (1040, 1392, 16) double; % Conteneur de la matrice d'image des bruit électroniques
        White (1040, 1392, :) double; % Conteneur de la matrice d'image de correction Flatfield
        Ref (1040, 1392, 16) double; % Conteneur de la matrice d'image de la mire de calib couleur
        SpectralRef (:,:) double = [];
        SpectralWavlgt (:,:) double = [];
        MetaData; % Conteneur des métadonnées des fichiers chargés
        AcqTimes (4,16); % Conteneur des temps d'acquisition, avec
        % AcqTimes[1,:]=Acqui; [2,:]=Black; [3,:]=White; [4,:]=Ref;
        AcquiID = 1; % Identifiant de la matrice d'acquisitions pour indexer les données
        BlackID = 2; % Identifiant de la matrice Black pour indexer les données
        WhiteID = 3; % Identifiant de la matrice White pour indexer les données
        RefID = 4; % Identifiant de la matrice Reference pour indexer les données
        FlatFieldGaussianSigma = 20; % Taux de lissage pour la correction flatfield statistique
        
        CorrectedCube (1040, 1392, 16) double; % Conteneur du cube d'image corrigées (toutes calib confondues)
        ContrastEnhancedCubeStock (1040, 1392, 16) double; % Conteneur du cube d'image avec contraste amélioré
        TrueColorsCube (1040, 1392, 3) double; % Conteneur du cube d'image RGB, après estimation spectrale
        DisplayedCube (1040, 1392, :) double; % Recopie du cube à afficher selon les options sélectionnées
        
        PcaDataCubeStock (1040,1392,:) double; % Conteneur du cube des composantes principales
        PcaCoeff; % Conteneur des coefficients affiliés aux composantes principales
        PcaVar; % Conteneur de la variance totale de la PCA.
        
        QMatrix; % Conteneur de la matrice de transformation multispectral->spectral
        QMatrix_color; % Conteneur de la matrice de transformation multispectal -> RGB
        
        PCAFig; % Conteneur de la figure des données PCA
        spectralDataFig; % Conteneur de la figure du spectre pointé
        PixelSpectrum; % Dernier spectre calculé
        MetaDFig; % Conteneur de la figure de visu des métadonnées
        DataManagerFig; % Conteneur de la figure de sauvegarde/chargement des données.
    end
    
    methods (Access = private)
        function majConsole(app,msg)
            % Fonction de mise à jour de la console d'état de la GUI
            % Préciser simplement le msg à écrire en chartab
            app.ConsoleArea.Value = [app.ConsoleArea.Value; '> ' msg];
            pause(0.1);
            scroll(app.ConsoleArea,'bottom');
        end
        function majDisplay(app)
            % Fonction de mise à jour de l'image affichée et de
            % l'histogramme correspondant.
            selectedTab = app.ChoosenDisplayType.SelectedTab;
            if selectedTab == app.GreyScaleTab
                app.TrueColorsButton.Value = 0;
                app.DisplayedCube = app.CorrectedCube;
                imshow(app.DisplayedCube(:,:,app.FilterNbSpinner.Value),'Parent',app.ImageRendering);
                [counts,loc]=imhist(app.DisplayedCube(:,:,app.FilterNbSpinner.Value));
                bar(app.ImageDetailAxes,loc,counts/max(counts),'k');
            else
                Rindex = find(strcmp(app.CanalRDropDown.Value,app.CanalRDropDown.Items));
                Gindex = find(strcmp(app.CanalGDropDown.Value,app.CanalGDropDown.Items));
                Bindex = find(strcmp(app.CanalBDropDown.Value,app.CanalBDropDown.Items));
                app.DisplayedCube = zeros(app.PixelHeight,app.PixelWidth,3);
                app.DisplayedCube(:,:,1) = app.CorrectedCube(:,:,Rindex);
                app.DisplayedCube(:,:,2) = app.CorrectedCube(:,:,Gindex);
                app.DisplayedCube(:,:,3) = app.CorrectedCube(:,:,Bindex);
                [countsR,locR]=imhist(app.DisplayedCube(:,:,1));
                [countsG,locG]=imhist(app.DisplayedCube(:,:,2));
                [countsB,locB]=imhist(app.DisplayedCube(:,:,3));
                imshow(app.DisplayedCube,'Parent',app.ImageRendering);
                bar(app.ImageDetailAxes,locR,countsR/max(countsR),'r');
                hold(app.ImageDetailAxes,'on');
                bar(app.ImageDetailAxes,locG,countsG/max(countsG),'g');
                bar(app.ImageDetailAxes,locB,countsB/max(countsB),'b');
                hold(app.ImageDetailAxes,'off');
            end
        end
        function razGui(app,whatToRAZ)
            % Fonction de réinitialisation des variables, permet
            % d'encapsuler un peu le code.
            switch whatToRAZ
                case 'Acqui'
                    app.Acqui = zeros(app.PixelHeight,app.PixelWidth,app.NbFilters);
                    app.AcquisitionsLoad.Checked = 'off';
                    app.DisplayMetadata.Enable = 'off';
                    
                case 'Black'
                    app.Black = zeros(app.PixelHeight,app.PixelWidth,app.NbFilters);
                    app.BlackLoad.Checked = 'off';
                    
                case 'White'
                    app.White = zeros(app.PixelHeight,app.PixelWidth,app.NbFilters);
                    app.WhiteLoad.Checked = 'off';
                    
                case 'Color'
                    app.Ref = zeros(app.PixelHeight,app.PixelWidth,app.NbFilters);
                    app.ReferenceLoad.Checked = 'off';
                    
                case 'OffsetCal'
                    app.OffsetCalibLamp.Color = [0.64,0.08,0.18];
                    app.OffsetCalib.Checked = 'off';
                    
                case 'FlatCal'
                    app.FlatfieldCalibLamp.Color = [0.64,0.08,0.18];
                    app.FlatfieldCalib.Checked = 'off';
                    
                case 'RegCal'
                    app.RegisterCalibLamp.Color = [0.64,0.08,0.18];
                    app.RegistrationCalib.Checked = 'off';
                    
                case 'ColorCal'
                    app.ColorCalibLamp.Color = [0.64,0.08,0.18];
                    app.ColorCalib.Checked = 'off';
                    
                case 'Computed-Spectral'
                    app.QMatrix = [];
                    app.QMatrix_color = [];
                    app.TrueColorsCube = zeros(app.PixelHeight,app.PixelWidth,3);
                    app.SpectralPointButton.Value = 0;
                    app.SpectralPointButton.Enable = 'off';
                    app.TrueColorsButton.Value = 0;
                    app.TrueColorsButton.Enable = 'off';
                    app.Hasimoto.Enable = 'off';
                    
                case 'Computed-Standard'
                    if (sum(app.Acqui,'all') == 0)
                        app.CorrectedCube = zeros(app.PixelHeight,app.PixelWidth,app.NbFilters);
                    else
                        app.CorrectedCube = app.Acqui;
                    end
                    app.ContrastEnhancedCubeStock = zeros(app.PixelHeight,app.PixelWidth,app.NbFilters);
                    app.PcaDataCubeStock = [];
                    app.PcaCoeff = [];
                    app.PcaVar = [];
                    app.ContrastStretchingButton.Value = 0;
                    app.PcaButton.Value = 0;
                    app.TrueColorsCube = zeros(app.PixelHeight,app.PixelWidth,3);
                    
                case 'Display'
                    app.FilterNbSpinner.Value = 16;
                    app.FilterNameDropDown.Items = app.FilterNames;
                    app.FilterNameDropDown.Value = app.FilterNames{app.FilterNbSpinner.Value};
                    app.FilterCenterEditField.Value = app.Wavelengths(app.FilterNbSpinner.Value);
                    app.FilterBwEditField.Value = app.BandWidths(app.FilterNbSpinner.Value);
                    app.CanalRDropDown.Items = app.FilterNames;
                    app.CanalGDropDown.Items = app.FilterNames;
                    app.CanalBDropDown.Items = app.FilterNames;
                    app.CanalRDropDown.Value = app.FilterNames{app.FilterNbSpinner.Value};
                    app.CanalGDropDown.Value = app.FilterNames{app.FilterNbSpinner.Value};
                    app.CanalBDropDown.Value = app.FilterNames{app.FilterNbSpinner.Value};
                    app.ChoosenDisplayType.SelectedTab = app.GreyScaleTab;
                    app.TrueColorsButton.Value = 0;
                    %app.TrueColorsButton.Enable = 'off';
                    app.DisplayedCube = app.Acqui;
                    imshow(app.DisplayedCube(:,:,app.FilterNbSpinner.Value),'Parent',app.ImageRendering);
                    [counts,loc]=imhist(app.DisplayedCube(:,:,app.FilterNbSpinner.Value));
                    bar(app.ImageDetailAxes,loc,counts/max(counts),'k');
                    
                case 'All'
                    app.MetaData = [];
                    app.DisplayTypePanel.Enable = 'off';
                    app.ProcessPanel.Enable = 'off';
                    app.CalibrationMenu.Enable = 'off';
                otherwise
                    uialert(app.UIFigure,'Erreur de code!','Fatal error');
            end
        end
    end
    
    methods (Static)
        corrected = flatfield_exp(acqui,white,black);
        outputCube = registration(nbFilters,inputCube);
        [QMatrix, QMatrix_color] = spectralCalib(spectralRef,spectralWlgt,pixelH,pixelW,multispecRef,appFilePath,spectralPath);
        trueColorCube = trueColorRendering(multispecCube,Qmatrix,spectralW,pixelW,pixelH,meanWidth,meanHeight);
        rgbColorCube = rgbColorRendering(multispecCube,Qmatrix,pixelW,pixelH);
        %         [U,l] = eigen(X);
        %         [P,a,Q] = paq(X,k);
        %         [W,e] = heterass(X,T,eta,it);
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            s = path;
            sub = strsplit(s,';');
            s=sub{1};            
            addpath(genpath([s '\+MultispectralViewerPkg\Ressources']));
            currentFile = mfilename( 'fullpath' );
            app.SpectralFolder=[currentFile(1:end-40) 'Ressources\MatricesSpectres&RGB'];
        end

        % Menu selected function: AcquisitionsLoad
        function AcquisitionsLoadMenuSelected(app, event)
            % Verification de données présentes
            if (sum(app.Acqui,'all') ~= 0)
                selection=uiconfirm(app.UIFigure,"Voulez-vous écraser les données chargées (sans changer les données de calibration le cas échéant)?", "Réécriture",'Icon','Warning','Options',{'Oui','Non'},"DefaultOption",'Non',"CancelOption",'Non');
            end
            
            if (sum(app.Acqui,'all') == 0 || strcmp(selection,'Oui'))
                % Chargement du cube d'image
                [app.FileNames{app.AcquiID}, app.FilePath] = uigetfile([app.FilePath '/' '*.tiff']);
                for i = 1 : app.NbFilters
                    app.Acqui(:,:,i) = imread([app.FilePath, '/', app.FileNames{app.AcquiID}],'tiff',i);
                end
                % Chargement des métadonnées (étant identiques pour tous
                % les paramètres sauf les temps d'acquisitions, on
                % initialise ici tous les champs, et seuls les
                % MetaData.XXXRef seront sauvergardé dans les autres
                % fonctions de chargement de données.
                globalMetaData = imfinfo([app.FilePath, '/', app.FileNames{app.AcquiID}]);
                app.MetaData.DocName = globalMetaData.DocumentName;
                app.MetaData.Description = globalMetaData.ImageDescription;
                app.MetaData.Maker = globalMetaData.Make;
                app.MetaData.Model = globalMetaData.Model;
                app.MetaData.Copyright = globalMetaData.Copyright;
                app.MetaData.BitDepth = globalMetaData.BitDepth;
                app.Acqui = app.Acqui/(2^app.MetaData.BitDepth-1);
                for i = 1 : app.NbFilters
                    app.MetaData.AcquiRefs{i} = globalMetaData(i).PageName;
                    splitted = split(app.MetaData.AcquiRefs{i},'_');
                    splitted = splitted{2};
                    app.AcqTimes(app.AcquiID,i) = str2double(splitted(1:end-1));
                end
                % Remise à zéro des variables
                app.razGui('OffsetCal');
                app.razGui('FlatCal');
                app.razGui('RegCal');
                app.razGui('Computed-Standard');
                % Mise à jour de la GUI
                app.AcquisitionsLoad.Checked = 'on';
                app.DisplayTypePanel.Enable = 'on';
                app.ProcessPanel.Enable = 'on';
                app.CalibrationMenu.Enable = 'on';
                app.DisplayMetadata.Enable = 'on';
                % Mise à jour de la console
                app.majConsole('Données d''aquisition chargées.');
                % Mise à jour de l'image affichée de base.
                app.razGui('Display');
                app.majConsole('Cliché sans filtre chargé.');
            end
        end

        % Menu selected function: BlackLoad
        function BlackLoadMenuSelected(app, event)
            % Verification de la présence de données
            if (sum(app.Black,'all') ~= 0)
                selection=uiconfirm(app.UIFigure,"Voulez-vous écraser les données de calibration 'Black' chargées (sans changer les autres données le cas échéant)?", "Réécriture",'Icon','Warning','Options',{'Oui','Non'},"DefaultOption",'Non',"CancelOption",'Non');
            end
            
            if (sum(app.Black,'all') == 0 || strcmp(selection,'Oui'))
                % Chargement des données
                [app.FileNames{app.BlackID}, app.FilePath] = uigetfile([app.FilePath '/' '*.tiff']);
                for i = 1 : app.NbFilters
                    app.Black(:,:,i) = imread([app.FilePath, '/', app.FileNames{app.BlackID}],'tiff',i);
                end
                app.Black = app.Black/(2^app.MetaData.BitDepth-1);
                % Mise à jour des métadonnées
                globalMetaData = imfinfo([app.FilePath, '/', app.FileNames{app.BlackID}]);
                for i = 1 : app.NbFilters
                    app.MetaData.BlackRefs{i} = globalMetaData(i).PageName;
                    splitted = split(app.MetaData.BlackRefs{i},'_');
                    splitted = splitted{2};
                    app.AcqTimes(app.BlackID,i) = str2double(splitted(1:end-1));
                end
                % Remise à zéro des variables et mise à jour de la GUI
                app.razGui('OffsetCal');
                app.razGui('FlatCal');
                app.razGui('RegCal');
                app.razGui('Computed-Standard');
                app.razGui('Display');
                app.BlackLoad.Checked = 'on';
                app.majConsole('Données de calibration ''Black'' chargées.');
            end
        end

        % Menu selected function: WhiteLoad
        function WhiteLoadMenuSelected(app, event)
            % Verification de la présence de données
            if (sum(app.White,'all') ~= 0)
                selection=uiconfirm(app.UIFigure,"Voulez-vous écraser les données de calibration 'White' chargées (sans changer les autres données le cas échéant)?", "Réécriture",'Icon','Warning','Options',{'Oui','Non'},"DefaultOption",'Non',"CancelOption",'Non');
            end
            
            if (sum(app.White,'all') == 0 || strcmp(selection,'Oui'))
                % Chargement des données
                [app.FileNames{app.WhiteID}, app.FilePath] = uigetfile([app.FilePath '/' '*.tiff']);
                for i = 1 : app.NbFilters
                    app.White(:,:,i) = imread([app.FilePath, '/', app.FileNames{app.WhiteID}],'tiff',i);
                end
                app.White = app.White/(2^app.MetaData.BitDepth-1);
                % Mise à jour des métadonnées
                globalMetaData = imfinfo([app.FilePath, '/', app.FileNames{app.WhiteID}]);
                for i = 1 : app.NbFilters
                    app.MetaData.WhiteRefs{i} = globalMetaData(i).PageName;
                    splitted = split(app.MetaData.WhiteRefs{i},'_');
                    splitted = splitted{2};
                    app.AcqTimes(app.WhiteID,i) = str2double(splitted(1:end-1));
                end
                % Remise à zéro des variables et mise à jour de la GUI
                app.razGui('FlatCal');
                app.razGui('RegCal');
                app.razGui('Computed-Standard');
                app.razGui('Display');
                app.WhiteLoad.Checked = 'on';
                app.majConsole('Données de calibration ''White'' chargées.');
            end
        end

        % Menu selected function: ReferenceLoad
        function ReferenceLoadMenuSelected(app, event)
            % Vérification de la présence de données
            if (sum(app.Ref,'all') ~= 0)
                selection=uiconfirm(app.UIFigure,"Voulez-vous écraser les données de calibration 'Ref' chargées (sans changer les autres données le cas échéant)?", "Réécriture",'Icon','Warning','Options',{'Oui','Non'},"DefaultOption",'Non',"CancelOption",'Non');
            end
            
            if (sum(app.Ref,'all') == 0 || strcmp(selection,'Oui'))
                % Chargement des données
                [app.FileNames{app.RefID}, app.FilePath] = uigetfile([app.FilePath '/' '*.tiff']);
                for i = 1 : app.NbFilters
                    app.Ref(:,:,i) = imread([app.FilePath, '/', app.FileNames{app.RefID}],'tiff',i);
                end
                app.Ref = app.Ref/(2^app.MetaData.BitDepth-1);
                % Mise à jour des métadonnées
                globalMetaData = imfinfo([app.FilePath, '/', app.FileNames{app.RefID}]);
                for i = 1 : app.NbFilters
                    app.MetaData.RefRefs{i} = globalMetaData(i).PageName;
                    splitted = split(app.MetaData.RefRefs{i},'_');
                    splitted = splitted{2};
                    app.AcqTimes(app.RefID,i) = str2double(splitted(1:end-1));
                end
                % Remise à zéro des variables et mise à jour de la GUI
                app.razGui('ColorCal');
                app.razGui('Computed-Spectral');
                app.razGui('Display');
                app.ReferenceLoad.Checked = 'on';
                app.majConsole('Données de calibration ''Ref'' chargées.');
            end
        end

        % Menu selected function: ClearLoaded
        function ClearLoadedMenuSelected(app, event)
            % Vérification de la volonté de l'utilisateur
            selection=uiconfirm(app.UIFigure,"Voulez-vous écraser toutes les données chargées et réinitialiser l'interface?", "Remise à zéro",'Icon','Warning','Options',{'Oui','Non'},"DefaultOption",'Non',"CancelOption",'Non');
            if strcmp(selection,'Oui')
                app.razGui('Acqui');
                app.razGui('Black');
                app.razGui('White');
                app.razGui('Color');
                app.razGui('OffsetCal');
                app.razGui('FlatCal');
                app.razGui('RegCal');
                app.razGui('ColorCal');
                app.razGui('Computed-Spectral');
                app.razGui('Computed-Standard');
                app.razGui('Display');
                app.razGui('All');
                app.majDisplay();
                app.majConsole('Remise à zéro effectuée.');
            end
        end

        % Menu selected function: OffsetCalib
        function OffsetCalibMenuSelected(app, event)
            % Verification de la présence de données
            if (sum(app.Black,'all') == 0)
                uialert(app.UIFigure,'Données de calibration "Black" non chargées','Absence de données');
                return;
            else
                % Correction du bruit de fond
                app.CorrectedCube = app.Acqui-app.Black;
                if (sum(app.Ref,'all') ~= 0)
                    selection=uiconfirm(app.UIFigure,'Voulez-vous soustraire le bruit de fond de la référence?','Correction offset ref','Icon','question','Options',{'Oui','Non'},"DefaultOption",'Non',"CancelOption",'Non');
                    if ~strcmp(selection,'Oui')
                        app.Ref = app.Ref-app.Black;
                    end
                end
                % Changement de la matrice à afficher
                app.DisplayedCube = app.CorrectedCube;
                % Affichage de l'image en cours
                app.majDisplay();
                % Mise à jour de la GUI
                app.OffsetCalib.Checked = 'on';
                app.OffsetCalibLamp.Color = [0 1 0];
                % Mise à jour de la console d'état
                app.majConsole('Correction du bruit de fond.');
            end
        end

        % Menu selected function: FlatfieldCalib
        function FlatfieldCalibMenuSelected(app, event)
            % Verification de la présence de données 'black'
            if (sum(app.Black,'all') == 0)
                selection=uiconfirm(app.UIFigure,'Êtes-vous sûr de vouloir procéder à la calibration Flatfield sans avoir chargé de données pour corriger l''offset ?','Correction offset manquante','Icon','Warning','Options',{'Oui','Non'},"DefaultOption",'Non',"CancelOption",'Non');
                if ~strcmp(selection,'Oui')
                    return;
                end
            end
            % Verification de la présences de données expérimentales pour
            % la correction flatfield, sinon proposition de continuer avec
            % une correction statistique.
            if (sum(app.White,'all') == 0)
                selection=uiconfirm(app.UIFigure,'Pas de données "White" chargées, souhaitez-vous continuer avec une estimation gaussienne?','Absence de données','Icon','Warning','Options',{'Oui','Non'},"DefaultOption",'Non',"CancelOption",'Non');
            else
                selection=uiconfirm(app.UIFigure,'Souhaitez-vous travailler avec les données "White" chargées, ou effectuer avec estimation gaussienne?','Choix du type de traitement','Icon','Warning','Options',{'Flatfield expérimental','Flatfield gaussien','Annuler'},"DefaultOption",'Annuler',"CancelOption",'Annuler');
            end
            
            if strcmp(selection,'Oui')||strcmp(selection,'Flatfield gaussien')
                app.FlatFieldGaussianSigma=str2double(inputdlg('Quel taux de lissage souhaitez-vous appliquer?','Sigma',1,{num2str(app.FlatFieldGaussianSigma)}));
                for i = 1 : app.NbFilters
                    app.CorrectedCube(:,:,i) = imflatfield(app.Acqui(:,:,i)-app.Black(:,:,i),app.FlatFieldGaussianSigma);
                end
            elseif strcmp(selection,'Flatfield expérimental')
                app.CorrectedCube = MultispectralViewerPkg.MultispectralViewer.flatfield_exp(app.Acqui,app.White,app.Black);
            else
                return;
            end
            % Mise à jour de la matrice à afficher
            app.DisplayedCube = app.CorrectedCube;
            % Mise à jour de la GUI
            app.FlatfieldCalib.Checked = 'on';
            app.FlatfieldCalibLamp.Color = [0 1 0];
            app.majDisplay();
            app.majConsole('Correction flatfield effectuée');
        end

        % Menu selected function: RegistrationCalib
        function RegistrationCalibMenuSelected(app, event)
            % Vérification de la complétion des calib de base (sinon la
            % mise en registre, gourmande en temps de traitement, est
            % inutile)
            if strcmp(app.OffsetCalib.Checked,'Off') || strcmp(app.FlatfieldCalib.Checked,'Off')
                uialert(app.UIFigure,'Calibrations Offset et Flatfield non effectuées','Données non calibrées');
                return;
            end
            app.majConsole('Début de la mise en registre.')
            % On travaille sur les N-1 images obtenues avec la roue à
            % filtre, car la Nième correspond à la prise sans filtre et
            % sert donc de référénce
            app.CorrectedCube = MultispectralViewerPkg.MultispectralViewer.registration(app.NbFilters,app.CorrectedCube);
            % Une fois toutes les images recalées, on présente les montages
            % avant/après
            figure(1);
            montage(app.Acqui);
            title('Avant recalage');
            figure(2)
            montage(app.CorrectedCube);
            title('Après recalage');
            % Mise à jour de la matrice d'image à afficher
            app.DisplayedCube = app.CorrectedCube;
            % Mise à jour de la GUI
            app.RegistrationCalib.Checked = 'on';
            app.RegisterCalibLamp.Color = [0 1 0];
            app.majDisplay();
            app.majConsole('Mise en registre effectuée.');
        end

        % Menu selected function: ColorCalib
        function ColorCalibMenuSelected(app, event)
            if (sum(app.Ref,'all') == 0)
                uialert(app.UIFigure,'Données de calibration "Ref" non chargées','Absence de données');
                return;
            end
            app.majConsole('Début de la calibration couleur');
            app.razGui('Computed-Spectral');
            
            % Chargement des données spectrales
            if ~isempty(app.SpectralRef) || ~isempty(app.SpectralWavlgt)
                selection=uiconfirm(app.UIFigure,"Voulez-vous écraser les données spectrales chargées ?", "Réécriture spectrale",'Icon','Warning','Options',{'Oui','Non'},"DefaultOption",'Non',"CancelOption",'Non');
            end
            
            if isempty(app.SpectralRef)||strcmp(selection,'Oui')
                app.SpectralRef = [];
                app.SpectralWavlgt = [];
                [FileName, Filepath] = uigetfile(app.SpectralFolder,'Selectionner le fichier des données spectrales de la mire acquise.');
                load([Filepath '/' FileName]); 
                app.SpectralRef = spectra;
                app.SpectralWavlgt = wavelengths;
                clear spectra wavelenghts;
            end
            
            [app.QMatrix, app.QMatrix_color] = MultispectralViewerPkg.MultispectralViewer.spectralCalib(app.SpectralRef,app.SpectralWavlgt,app.PixelHeight,app.PixelWidth,app.Ref,app.FilePath,app.SpectralFolder);
            
            if ~isempty(app.QMatrix_color)
                app.Hasimoto.Enable = 'on';
            else
                app.Hasimoto.Enable = 'off';
            end
            
            app.ColorCalib.Checked = 'on';
            app.SpectralPointButton.Enable = 'on';
            app.TrueColorsButton.Enable = 'on';
            app.ColorCalibLamp.Color = [0 1 0];
            app.majConsole('Calibration couleur ok.');
            
        end

        % Selection change function: ChoosenDisplayType
        function ChoosenDisplayTypeSelectionChanged(app, event)
            app.majDisplay();
        end

        % Value changed function: FilterNbSpinner
        function FilterNbSpinnerValueChanged(app, event)
            app.FilterNameDropDown.Value = app.FilterNameDropDown.Items{app.FilterNbSpinner.Value};
            app.FilterCenterEditField.Value = app.Wavelengths(app.FilterNbSpinner.Value);
            app.FilterBwEditField.Value = app.BandWidths(app.FilterNbSpinner.Value);
            
            imshow(app.DisplayedCube(:,:,app.FilterNbSpinner.Value),'Parent',app.ImageRendering);
            [counts,loc]=imhist(app.DisplayedCube(:,:,app.FilterNbSpinner.Value));
            bar(app.ImageDetailAxes,loc,counts/max(counts),'k');
        end

        % Value changed function: FilterNameDropDown
        function FilterNameDropDownValueChanged(app, event)
            value = app.FilterNameDropDown.Value;
            pos = find(strcmp(value,app.FilterNameDropDown.Items));
            
            app.FilterNbSpinner.Value = pos;
            app.FilterCenterEditField.Value = app.Wavelengths(app.FilterNbSpinner.Value);
            app.FilterBwEditField.Value = app.BandWidths(app.FilterNbSpinner.Value);
            
            imshow(app.DisplayedCube(:,:,app.FilterNbSpinner.Value),'Parent',app.ImageRendering);
            [counts,loc]=imhist(app.DisplayedCube(:,:,app.FilterNbSpinner.Value));
            bar(app.ImageDetailAxes,loc,counts/max(counts),'k');
        end

        % Button pushed function: ApplyFalseColorButton
        function ApplyFalseColorButtonPushed(app, event)
            Rindex = find(strcmp(app.CanalRDropDown.Value,app.CanalRDropDown.Items));
            Gindex = find(strcmp(app.CanalGDropDown.Value,app.CanalGDropDown.Items));
            Bindex = find(strcmp(app.CanalBDropDown.Value,app.CanalBDropDown.Items));
            app.DisplayedCube = zeros(app.PixelHeight,app.PixelWidth,3);
            app.DisplayedCube(:,:,1) = app.CorrectedCube(:,:,Rindex);
            app.DisplayedCube(:,:,2) = app.CorrectedCube(:,:,Gindex);
            app.DisplayedCube(:,:,3) = app.CorrectedCube(:,:,Bindex);
            [countsR,locR]=imhist(app.DisplayedCube(:,:,1));
            [countsG,locG]=imhist(app.DisplayedCube(:,:,2));
            [countsB,locB]=imhist(app.DisplayedCube(:,:,3));
            imshow(app.DisplayedCube,'Parent',app.ImageRendering);
            bar(app.ImageDetailAxes,locR,countsR/max(countsR),'r');
            hold(app.ImageDetailAxes,'on');
            bar(app.ImageDetailAxes,locG,countsG/max(countsG),'g');
            bar(app.ImageDetailAxes,locB,countsB/max(countsB),'b');
            hold(app.ImageDetailAxes,'off');
        end

        % Value changed function: ContrastStretchingButton
        function ContrastStretchingButtonValueChanged(app, event)
            value = app.ContrastStretchingButton.Value;
            if value == 1
                app.ContrastEnhancedCubeStock = app.CorrectedCube;
                app.CorrectedCube = histeq(app.CorrectedCube);
                app.majDisplay();
                app.majConsole('Amélioration de constraste par egalisation d''histogramme : activé.');
            else
                app.CorrectedCube = app.ContrastEnhancedCubeStock;
                app.ContrastEnhancedCubeStock = zeros(app.PixelHeight,app.PixelWidth,app.NbFilters);
                app.majDisplay();
                app.majConsole('Amélioration de constraste par egalisation d''histogramme : désactivé.');
            end
        end

        % Value changed function: PcaButton
        function PcaButtonValueChanged(app, event)
            value = app.PcaButton.Value;
            if value == 1
                app.PcaDataCubeStock = app.CorrectedCube;
                hcube = app.CorrectedCube(:,:,1:app.NbFilters-1);
                [pcaCube,app.PcaCoeff,app.PcaVar] = hyperpca(hcube,app.NbFilters-1);
                reshapedPCACube = reshape(pcaCube,[app.PixelHeight*app.PixelWidth,app.NbFilters-1]);
                reshapedPCACube = reshapedPCACube';
                standardizedPCACube = (reshapedPCACube - min(reshapedPCACube,[],2))./(max(reshapedPCACube,[],2) - min(reshapedPCACube,[],2));
                app.CorrectedCube(:,:,1:app.NbFilters-1)=reshape(standardizedPCACube',[app.PixelHeight,app.PixelWidth,app.NbFilters-1]);
                app.CorrectedCube(:,:,16) = zeros(app.PixelHeight,app.PixelWidth);
                
                app.PCAFig = MultispectralViewerPkg.DisplayPCAData(app.PcaVar,app.PcaCoeff);
                
                app.FilterNameDropDown.Enable = 'off';
                app.FilterNameDropDown.Items = {'PC1','PC2','PC3','PC4',...
                    'PC5','PC6','PC7','PC8','PC9','PC10','PC11','PC12',...
                    'PC13','PC14','PC15'};
                app.FilterCenterEditField.Visible = 'off';
                app.FilterBwEditField.Visible = 'off';
                app.CanalRDropDown.Items = {'PC1','PC2','PC3','PC4',...
                    'PC5','PC6','PC7','PC8','PC9','PC10','PC11','PC12',...
                    'PC13','PC14','PC15'};
                app.CanalGDropDown.Items = {'PC1','PC2','PC3','PC4',...
                    'PC5','PC6','PC7','PC8','PC9','PC10','PC11','PC12',...
                    'PC13','PC14','PC15'};
                app.CanalBDropDown.Items = {'PC1','PC2','PC3','PC4',...
                    'PC5','PC6','PC7','PC8','PC9','PC10','PC11','PC12',...
                    'PC13','PC14','PC15'};
                app.FilterNbSpinner.Value = 1;
                app.ICA.Enable = 'on';
                app.majDisplay();
                app.majConsole('Analyse en composantes principales : activé.');
            else
                app.CorrectedCube = app.PcaDataCubeStock;
                app.PcaDataCubeStock = zeros(app.PixelHeight,app.PixelWidth,app.NbFilters);
                
                app.FilterNameDropDown.Enable = 'on';
                app.FilterNameDropDown.Items = app.FilterNames;
                app.FilterCenterEditField.Visible = 'on';
                app.FilterBwEditField.Visible = 'on';
                app.CanalRDropDown.Items = app.FilterNames;
                app.CanalGDropDown.Items = app.FilterNames;
                app.CanalBDropDown.Items = app.FilterNames;
                app.ICA.Enable = 'off';
                app.majDisplay();
                app.majConsole('Analyse en composantes principales : désactivé.');
            end
        end

        % Value changed function: SpectralPointButton
        function SpectralPointButtonValueChanged(app, event)
            value = app.SpectralPointButton.Value;
            
            if app.ContrastStretchingButton.Value == 1
                workingCube = app.ContrastEnhancedCubeStock;
            elseif app.PcaButton.Value == 1
                workingCube = app.PcaDataCubeStock;
            else
                workingCube = app.CorrectedCube;
            end
            
            if value == 1
                x = round(app.PixelWidth/2);
                y = round(app.PixelHeight/2);
                app.PixelSpectrum = app.QMatrix * squeeze(workingCube(y,x,1:15));
                hold(app.ImageRendering,'on')
                scatter(app.ImageRendering,x,y,'o','g');
                hold(app.ImageRendering,'off')
                app.ImageRendering.Children(1).PickableParts = 'none';
                app.ImageRendering.Children(2).PickableParts = 'none';
                figure(55);
                app.spectralDataFig = plot(app.SpectralWavlgt,app.PixelSpectrum);
                axis([app.SpectralWavlgt(1) app.SpectralWavlgt(end) 0 1]);
                title('Spectre estimé');
                xlabel('Longueurs d''ondes \lambda [nm]');
                ylabel('Réflectance ramenée entre 0 et 1');
                
                app.majConsole('Affichage du spectre pointé par la souris : activé.');
            else
                if app.TrueColorsButton.Value ==1
                    app.DisplayedCube = app.TrueColorsCube;
                    [countsR,locR]=imhist(app.DisplayedCube(:,:,1));
                    [countsG,locG]=imhist(app.DisplayedCube(:,:,2));
                    [countsB,locB]=imhist(app.DisplayedCube(:,:,3));
                    imshow(app.DisplayedCube,'Parent',app.ImageRendering);
                    bar(app.ImageDetailAxes,locR,countsR/max(countsR),'r');
                    hold(app.ImageDetailAxes,'on');
                    bar(app.ImageDetailAxes,locG,countsG/max(countsG),'g');
                    bar(app.ImageDetailAxes,locB,countsB/max(countsB),'b');
                    hold(app.ImageDetailAxes,'off');
                else
                    app.majDisplay();
                end                
                app.majConsole('Affichage du spectre pointé par la souris : désactivé.');
            end
        end

        % Button down function: ImageRendering
        function ImageRenderingButtonDown(app, event)
            if app.SpectralPointButton.Value == 1
                x = round(event.Source.CurrentPoint(1,1)); % ou event.IntersectionPoint(1)
                y = round(event.Source.CurrentPoint(1,2)); % ou event.IntersectionPoint(2)
                
                if app.ContrastStretchingButton.Value == 1
                    workingCube = app.ContrastEnhancedCubeStock;
                elseif app.PcaButton.Value == 1
                    workingCube = app.PcaDataCubeStock;
                else
                    workingCube = app.CorrectedCube;
                end
                
                app.PixelSpectrum = app.QMatrix * squeeze(workingCube(y,x,1:15));
                delete(app.ImageRendering.Children(1));
                hold(app.ImageRendering,'on')
                scatter(app.ImageRendering,x,y,'o','g');
                hold(app.ImageRendering,'off')
                app.ImageRendering.Children(1).PickableParts = 'none';
                app.ImageRendering.Children(2).PickableParts = 'none';
                
                app.spectralDataFig = plot(app.SpectralWavlgt,app.PixelSpectrum);
                axis([app.SpectralWavlgt(1) app.SpectralWavlgt(end) 0 1]);
                title(['Spectre estimé']);
                xlabel('Longueurs d''ondes \lambda [nm]');
                ylabel('Réflectance ramenée entre 0 et 1');
            end
        end

        % Value changed function: TrueColorsButton
        function TrueColorsButtonValueChanged(app, event)
            value = app.TrueColorsButton.Value;
            if value == 1
                
                if (sum(app.TrueColorsCube,'all') == 0)
                    app.majConsole('Calcul du cube RGB.');
                    if app.ContrastStretchingButton.Value == 1
                        workingCube = app.ContrastEnhancedCubeStock(:,:,1:app.NbFilters-1);
                    elseif app.PcaButton.Value == 1
                        workingCube = app.PcaDataCubeStock(:,:,1:app.NbFilters-1);
                    else
                        workingCube = app.CorrectedCube(:,:,1:app.NbFilters-1);
                    end
                    
                    if ~isempty(app.QMatrix_color) && strcmp(uiconfirm(app.UIFigure,'Souhaitez-vous utiliser l''estimation directe RGB?','Utilisation estimation RGB','Options',{'Oui','Non'},"CancelOption",'Oui',"DefaultOption",'Oui'),'Oui')
                        app.TrueColorsCube = MultispectralViewerPkg.MultispectralViewer.rgbColorRendering(workingCube,app.QMatrix_color,app.PixelWidth,app.PixelHeight);
                    else
                        meanWidth = 2;     %8       %16        %24
                        meanHeight = 2;    %8 30min %13 10min  %20 on est à 30s de traitement
                        app.TrueColorsCube = MultispectralViewerPkg.MultispectralViewer.trueColorRendering(workingCube,app.QMatrix,app.SpectralWavlgt,app.PixelWidth,app.PixelHeight,meanWidth,meanHeight);
                    end
                end
                app.majConsole('Affichage du cube RGB.');
                app.CanalRDropDown.Items = {'E-Red', 'E-Green', 'E-Blue'};
                app.CanalGDropDown.Items = {'E-Red', 'E-Green', 'E-Blue'};
                app.CanalBDropDown.Items = {'E-Red', 'E-Green', 'E-Blue'};
                app.CanalRDropDown.Value = 'E-Red';
                app.CanalGDropDown.Value = 'E-Green';
                app.CanalBDropDown.Value = 'E-Blue';
                app.CanalRDropDown.Enable = 'off';
                app.CanalGDropDown.Enable = 'off';
                app.CanalBDropDown.Enable = 'off';
                app.ApplyFalseColorButton.Enable = 'off';
                app.ChoosenDisplayType.SelectedTab = app.FalseColorTab;
                
                app.DisplayedCube = app.TrueColorsCube;
                
                [countsR,locR]=imhist(app.DisplayedCube(:,:,1));
                [countsG,locG]=imhist(app.DisplayedCube(:,:,2));
                [countsB,locB]=imhist(app.DisplayedCube(:,:,3));
                imshow(app.DisplayedCube,'Parent',app.ImageRendering);
                bar(app.ImageDetailAxes,locR,countsR/max(countsR),'r');
                hold(app.ImageDetailAxes,'on');
                bar(app.ImageDetailAxes,locG,countsG/max(countsG),'g');
                bar(app.ImageDetailAxes,locB,countsB/max(countsB),'b');
                hold(app.ImageDetailAxes,'off');
                
                app.ContrastStretchingButton.Enable = 'off';
                app.PcaButton.Enable = 'off';
            else
                app.DisplayedCube = app.CorrectedCube;
                
                app.CanalRDropDown.Items = app.FilterNames;
                app.CanalGDropDown.Items = app.FilterNames;
                app.CanalBDropDown.Items = app.FilterNames;
                app.CanalRDropDown.Value = 'None';
                app.CanalGDropDown.Value = 'None';
                app.CanalBDropDown.Value = 'None';
                app.CanalRDropDown.Enable = 'on';
                app.CanalGDropDown.Enable = 'on';
                app.CanalBDropDown.Enable = 'on';
                app.ApplyFalseColorButton.Enable = 'on';
                
                app.ContrastStretchingButton.Enable = 'on';
                app.PcaButton.Enable = 'on';
                app.majConsole('Retour à l''affichage normal.');
                app.majDisplay;
            end
        end

        % Menu selected function: DisplayMetadata
        function DisplayMetadataMenuSelected(app, event)
            app.MetaDFig = uifigure('Name','Visualisation des métadonnées');
            app.MetaDFig.Position = [500 300 400 300];
            std_step = 10;
            std_height = 20;
            
            lbl_main = uilabel(app.MetaDFig);
            lbl_main.Text = "Métadonnées du fichier d''acquisition chargé :";
            lbl_main.FontSize = 16;
            lbl_main.FontWeight = 'bold';
            lbl_main.HorizontalAlignment = 'center';
            lbl_main.Position = [0 app.MetaDFig.Position(4)-std_height app.MetaDFig.Position(3) std_height];
            
            lbl_docname = uilabel(app.MetaDFig);
            lbl_docname.Text = "Nom du document :";
            lbl_docname.FontSize = 12;
            lbl_docname.FontWeight = 'bold';
            lbl_docname.HorizontalAlignment = 'left';
            lbl_docname.Position = [10 lbl_main.Position(2)-std_step-std_height app.MetaDFig.Position(3)/2-10 std_height];
            
            lbl_maker = uilabel(app.MetaDFig);
            lbl_maker.Text = "Fabricant de la caméra :";
            lbl_maker.FontSize = 12;
            lbl_maker.FontWeight = 'bold';
            lbl_maker.HorizontalAlignment = 'left';
            lbl_maker.Position = [10 lbl_docname.Position(2)-std_step-std_height app.MetaDFig.Position(3)/2-10 std_height];
            
            lbl_model = uilabel(app.MetaDFig);
            lbl_model.Text = "Modèle de la caméra :";
            lbl_model.FontSize = 12;
            lbl_model.FontWeight = 'bold';
            lbl_model.HorizontalAlignment = 'left';
            lbl_model.Position = [10 lbl_maker.Position(2)-std_step-std_height app.MetaDFig.Position(3)/2-10 std_height];
            
            lbl_copyright = uilabel(app.MetaDFig);
            lbl_copyright.Text = "Copyright des données :";
            lbl_copyright.FontSize = 12;
            lbl_copyright.FontWeight = 'bold';
            lbl_copyright.HorizontalAlignment = 'left';
            lbl_copyright.Position = [10 lbl_model.Position(2)-std_step-std_height app.MetaDFig.Position(3)/2-10 std_height];
            
            lbl_bitdp = uilabel(app.MetaDFig);
            lbl_bitdp.Text = "Nombre de bits d'acquisition :";
            lbl_bitdp.FontSize = 12;
            lbl_bitdp.FontWeight = 'bold';
            lbl_bitdp.HorizontalAlignment = 'left';
            lbl_bitdp.Position = [10 lbl_copyright.Position(2)-std_step-std_height app.MetaDFig.Position(3)/2-10 std_height];
            
            lbl_descr = uilabel(app.MetaDFig);
            lbl_descr.Text = "Description :";
            lbl_descr.FontSize = 12;
            lbl_descr.FontWeight = 'bold';
            lbl_descr.HorizontalAlignment = 'left';
            lbl_descr.Position = [10 lbl_bitdp.Position(2)-std_step-std_height app.MetaDFig.Position(3)/2-10 std_height];
            
            data_docname = uieditfield(app.MetaDFig,'text');
            data_docname.HorizontalAlignment = 'right';
            data_docname.Position = [lbl_docname.Position(3)+10 lbl_docname.Position(2) app.MetaDFig.Position(3)/2-10 std_height];
            data_docname.Editable = 'off';
            data_docname.Value = app.MetaData.DocName;
            
            data_maker = uieditfield(app.MetaDFig,'text');
            data_maker.HorizontalAlignment = 'right';
            data_maker.Position = [lbl_maker.Position(3)+10 lbl_maker.Position(2) app.MetaDFig.Position(3)/2-10 std_height];
            data_maker.Editable = 'off';
            data_maker.Value = app.MetaData.Maker;
            
            data_model = uieditfield(app.MetaDFig,'text');
            data_model.HorizontalAlignment = 'right';
            data_model.Position = [lbl_model.Position(3)+10 lbl_model.Position(2) app.MetaDFig.Position(3)/2-10 std_height];
            data_model.Editable = 'off';
            data_model.Value = app.MetaData.Model;
            
            data_copyright = uieditfield(app.MetaDFig,'text');
            data_copyright.HorizontalAlignment = 'right';
            data_copyright.Position = [lbl_copyright.Position(3)+10 lbl_copyright.Position(2) app.MetaDFig.Position(3)/2-10 std_height];
            data_copyright.Editable = 'off';
            data_copyright.Value = app.MetaData.Copyright;
            
            data_bitdp = uieditfield(app.MetaDFig,'numeric');
            data_bitdp.HorizontalAlignment = 'right';
            data_bitdp.Position = [lbl_bitdp.Position(3)+10 lbl_bitdp.Position(2) app.MetaDFig.Position(3)/2-10 std_height];
            data_bitdp.Editable = 'off';
            data_bitdp.Value = app.MetaData.BitDepth;
            
            data_descr = uitextarea(app.MetaDFig);
            data_descr.Position = [lbl_descr.Position(3)+10 10 app.MetaDFig.Position(3)/2-10 lbl_descr.Position(2)+std_height-10];
            data_descr.Editable = 'off';
            
            relinedText = strsplit(app.MetaData.Description,'\r');
            words = cell(length(relinedText),1);
            for i = 1:length(relinedText)
                words{i}=strsplit(relinedText{i},'\t');
            end
            disptext = '';
            for i = 1:length(words)
                for j = 1:length(words{i})
                    temp = words{i};
                    if j == 1
                        disptext = sprintf([disptext '- ' temp{j} ]);
                    else
                        disptext = sprintf([disptext ' : ' temp{j} ]);
                    end
                end
                disptext = sprintf([disptext '\r\n']);
            end
            data_descr.Value = disptext;
        end

        % Menu selected function: DisplayAbout
        function DisplayAboutMenuSelected(app, event)
            date = 'septembre 2021';
            institution = 'le partenariat LMM-ECR et INP';
            cadre = 'vacation recherche';
            programmeur = 'Vincent Gauthier';
            ressources_icon = 'None';
            ressources_biblio1 = 'Reconstruction spectrale et mise en place de la caméra - Simon-Chane - DOI : 10.1366/14-07554';
            ressources_biblio2 = 'FastICA - Copyright (c) Hugo Gävert, Jarmo Hurri, Jaakko Särelä, and Aapo Hyvärinen. - DOI : S0893-6080(00)00026-5';
            ressources_biblio3 = 'Couleurs réhaussées - Hashimoto - DOI: 10.1364/OE.19.009315';
            ressources_biblio = sprintf(['\t- ' ressources_biblio1 '\n\t- ' ressources_biblio2 '\n\t- ' ressources_biblio3]);
            version = '1.0';
            text = sprintf(['Ce programme a été developpé en ' date ' dans le '...
                'cadre d''une ' cadre ' par ' programmeur ' pour ' ...
                institution '.\n\nVersion du programme : ' version '.\n\n'...
                'Crédits graphiques des icones : ' ressources_icon '\n\n' ...
                'Crédits bibliographiques :\n' ressources_biblio '.']);
            uialert(app.UIFigure,text,'A propos','Icon','info');
        end

        % Menu selected function: DisplayHelp
        function DisplayHelpMenuSelected(app, event)
            if exist("+MultispectralViewerPkg\Ressources\help.pdf","file")~=0
                open("+MultispectralViewerPkg\Ressources\help.pdf");
            elseif exist("+MultispectralViewerPkg\Ressources\pulp-fiction-john-travolta.gif","file")~=0
                web("+MultispectralViewerPkg\Ressources\pulp-fiction-john-travolta.gif");
            else
                uialert(app.UIFigure,'Le PDF d''aide n''a pas encore été rédigé, ou le fichier est introuvable...','Erreur d''ouverture');
            end
        end

        % Menu selected function: SaveProcessedData
        function SaveProcessedDataMenuSelected(app, event)
            app.majConsole('Lancement de l''utilitaire de sauvegarde/import');
            app.majConsole('Attention cette fonctionnalité peut être instable.');
            
            data.Acqui = app.Acqui;
            data.Black = app.Black;
            data.White = app.White;
            data.Ref = app.Ref;
            data.AcqTimes = app.AcqTimes;
            data.CorrectedCube = app.CorrectedCube;
            data.TrueColorsCube = app.TrueColorsCube;
            data.SpectralRef = app.SpectralRef;
            data.SpectralWavlgt = app.SpectralWavlgt;
            data.MetaData = app.MetaData;
            data.StateOfCorrection.Offset = app.OffsetCalib.Checked;
            data.StateOfCorrection.Flatfield = app.FlatfieldCalib.Checked;
            data.StateOfCorrection.Registration = app.RegistrationCalib.Checked;
            data.StateOfCorrection.ColorCalib = app.ColorCalib.Checked;
            data.QMatrix = app.QMatrix;
            data.QMatrix_color = app.QMatrix_color;
            
            app.DataManagerFig = MultispectralViewerPkg.DataManager(data);
            waitfor(app.DataManagerFig);
            loadFlag = evalin("base",'loadFlag');
            if loadFlag == 1
                filepath = evalin("base",'filepath');
                variableList = evalin("base",'variableToLoad');
                load(filepath,variableList{:});
                load(filepath,'StateOfCorrection');
                for i = 1 : length(variableList)
                    eval(['app.' variableList{i} ' = ' variableList{i} ';']);
                end
                
                if sum(matches(variableList,'Acqui'))
                    app.AcquisitionsLoad.Checked = 'on';
                    app.DisplayTypePanel.Enable = 'on';
                    app.ProcessPanel.Enable = 'on';
                    app.CalibrationMenu.Enable = 'on';
                    app.DisplayMetadata.Enable = 'on';
                end
                
                if sum(matches(variableList,'Black'))
                    app.BlackLoad.Checked = 'on';
                end
                
                if sum(matches(variableList,'White'))
                    app.WhiteLoad.Checked = 'on';
                end
                
                if sum(matches(variableList,'Ref'))
                    app.ReferenceLoad.Checked = 'on';
                end
                
                if sum(matches(variableList,'QMatrix'))
                    app.SpectralPointButton.Enable = 'on';
                    app.TrueColorsButton.Enable = 'on';
                end
                
                if strcmp(StateOfCorrection.Offset,'on')
                    app.OffsetCalib.Checked = 'on';
                    app.OffsetCalibLamp.Color = [0 1 0];
                else
                    app.razGui('OffsetCal');
                    
                end
                
                if strcmp(StateOfCorrection.Flatfield,'on')
                    app.FlatfieldCalib.Checked = 'on';
                    app.FlatfieldCalibLamp.Color = [0 1 0];
                else
                    app.razGui('FlatCal');
                end
                
                if strcmp(StateOfCorrection.Registration,'on')
                    app.RegistrationCalib.Checked = 'on';
                    app.RegisterCalibLamp.Color = [0 1 0];
                else
                    app.razGui('RegCal');
                end
                
                if strcmp(StateOfCorrection.ColorCalib,'on')
                    app.ColorCalib.Checked = 'on';
                    app.ColorCalibLamp.Color = [0 1 0];
                else
                    app.razGui('ColorCal');
                end
                
                delete(app.DataManagerFig);
                
                app.majConsole('Sauvegarde/Import effectué.');
            end
        end

        % Menu selected function: ICA
        function ICAMenuSelected(app, event)
            app.majConsole('Lancement de l''utilitaire ICA.');
            MultispectralViewerPkg.DisplayICA(app.PcaDataCubeStock(:,:,1:15), app.CorrectedCube(:,:,1:15), app.PcaCoeff, app.PcaVar);
            app.majConsole('Utilitaire ICA lancé.');
        end

        % Button pushed function: NegativeImgButton
        function NegativeImgButtonPushed(app, event)
            app.CorrectedCube(:,:,app.FilterNbSpinner.Value) = 1 - app.CorrectedCube(:,:,app.FilterNbSpinner.Value);
            app.majDisplay();
            app.majConsole('Calcul du négatif pour le canal sélectionné.');
        end

        % Menu selected function: Hasimoto
        function HasimotoMenuSelected(app, event)
            app.majConsole('Lancement de l''utilitaire de visualisation en couleur réhaussée.');
            MultispectralViewerPkg.HasimotoEnhancement(app.CorrectedCube(:,:,1:15),app.QMatrix_color);
            app.majConsole('Utilitaire de visualisation en couleur réhaussée lancé.');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {566, 566};
                app.GridLayout.ColumnWidth = {'1x'};
                app.DisplayPanel.Layout.Row = 2;
                app.DisplayPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {231, '1x'};
                app.DisplayPanel.Layout.Row = 1;
                app.DisplayPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.ChargementMenu = uimenu(app.UIFigure);
            app.ChargementMenu.Tooltip = {'Charger les différents fichiers ".tiff" acquis par l''application "Interface Multispectrale"'};
            app.ChargementMenu.Text = 'Chargement';
            app.AcquisitionsLoad = uimenu(app.ChargementMenu);
            app.AcquisitionsLoad.MenuSelectedFcn = createCallbackFcn(app, @AcquisitionsLoadMenuSelected, true);
            app.AcquisitionsLoad.Tooltip = {'Chargement des données références "Acqui"'};
            app.AcquisitionsLoad.Text = 'Acquisitions';
            app.BlackLoad = uimenu(app.ChargementMenu);
            app.BlackLoad.MenuSelectedFcn = createCallbackFcn(app, @BlackLoadMenuSelected, true);
            app.BlackLoad.Tooltip = {'Chargement des données références "Black", pour la correction de l''offset'};
            app.BlackLoad.Text = 'Black';
            app.WhiteLoad = uimenu(app.ChargementMenu);
            app.WhiteLoad.MenuSelectedFcn = createCallbackFcn(app, @WhiteLoadMenuSelected, true);
            app.WhiteLoad.Tooltip = {'Chargement des données référencées "White", pour la correction "Flatfield"'};
            app.WhiteLoad.Text = 'White';
            app.ReferenceLoad = uimenu(app.ChargementMenu);
            app.ReferenceLoad.MenuSelectedFcn = createCallbackFcn(app, @ReferenceLoadMenuSelected, true);
            app.ReferenceLoad.Tooltip = {'Chargement des données références "ref", pour la calibration spectrale'};
            app.ReferenceLoad.Text = 'Référence';
            app.ClearLoaded = uimenu(app.ChargementMenu);
            app.ClearLoaded.MenuSelectedFcn = createCallbackFcn(app, @ClearLoadedMenuSelected, true);
            app.ClearLoaded.Tooltip = {'Vider toutes les données chargées'};
            app.ClearLoaded.Text = 'Vider les données';
            app.CalibrationMenu = uimenu(app.UIFigure);
            app.CalibrationMenu.Tooltip = {'Calibrer les données d''acquisition par les données "black", "white", et "ref"'};
            app.CalibrationMenu.Enable = 'off';
            app.CalibrationMenu.Text = 'Calibration';
            app.OffsetCalib = uimenu(app.CalibrationMenu);
            app.OffsetCalib.MenuSelectedFcn = createCallbackFcn(app, @OffsetCalibMenuSelected, true);
            app.OffsetCalib.Tooltip = {'Corrige le bruit électronique d''acquisition'};
            app.OffsetCalib.Text = 'Correction de l''offset';
            app.FlatfieldCalib = uimenu(app.CalibrationMenu);
            app.FlatfieldCalib.MenuSelectedFcn = createCallbackFcn(app, @FlatfieldCalibMenuSelected, true);
            app.FlatfieldCalib.Tooltip = {'Corrige les aberrations géometriques optiques'};
            app.FlatfieldCalib.Text = 'Correction Flatfield';
            app.RegistrationCalib = uimenu(app.CalibrationMenu);
            app.RegistrationCalib.MenuSelectedFcn = createCallbackFcn(app, @RegistrationCalibMenuSelected, true);
            app.RegistrationCalib.Tooltip = {'Recale les images les unes par rapport aux autres.'};
            app.RegistrationCalib.Text = 'Mise en registre';
            app.ColorCalib = uimenu(app.CalibrationMenu);
            app.ColorCalib.MenuSelectedFcn = createCallbackFcn(app, @ColorCalibMenuSelected, true);
            app.ColorCalib.Tooltip = {'Lance la calibration spectre/couleur à partir de clichés d''une mire de couleur'};
            app.ColorCalib.Text = 'Calibration Spectre/Couleur';
            app.AdvancedProcessMenu = uimenu(app.UIFigure);
            app.AdvancedProcessMenu.Tooltip = {'Lancement de traitements d''image un peu plus poussés'};
            app.AdvancedProcessMenu.Text = 'Traitements avancés';
            app.ICA = uimenu(app.AdvancedProcessMenu);
            app.ICA.MenuSelectedFcn = createCallbackFcn(app, @ICAMenuSelected, true);
            app.ICA.Tooltip = {'Réalise une analyse en composantes indépendantes d''un point de vue statistique'};
            app.ICA.Enable = 'off';
            app.ICA.Text = 'Analyse en composantes indépendantes (FastICA)';
            app.Hasimoto = uimenu(app.AdvancedProcessMenu);
            app.Hasimoto.MenuSelectedFcn = createCallbackFcn(app, @HasimotoMenuSelected, true);
            app.Hasimoto.Tooltip = {'Affiche un rendu en vraie couleur de l''image multispectrale avec réhaussement d''une longueur d''onde choisie.'};
            app.Hasimoto.Enable = 'off';
            app.Hasimoto.Text = 'Traitement de réhaussement de couleur (Hashimoto)';
            app.Grifoni = uimenu(app.AdvancedProcessMenu);
            app.Grifoni.Tooltip = {'Affiche un rendu en vraie couleur de l''image multispectrale avec fusion des données obtenues sur les canaux infrarouges.'};
            app.Grifoni.Enable = 'off';
            app.Grifoni.Text = 'Traitement de mélange "Vraies Couleurs / IR" (Grifoni)';
            app.AutresMenu = uimenu(app.UIFigure);
            app.AutresMenu.Text = 'Autres';
            app.SaveProcessedData = uimenu(app.AutresMenu);
            app.SaveProcessedData.MenuSelectedFcn = createCallbackFcn(app, @SaveProcessedDataMenuSelected, true);
            app.SaveProcessedData.Tooltip = {'Sauvegarde et chargement des données traitées'};
            app.SaveProcessedData.Text = 'Sauvegarde/Chargement';
            app.DisplayMetadata = uimenu(app.AutresMenu);
            app.DisplayMetadata.MenuSelectedFcn = createCallbackFcn(app, @DisplayMetadataMenuSelected, true);
            app.DisplayMetadata.Tooltip = {'Affiche les métadonnées du fichier d''acquisition'};
            app.DisplayMetadata.Enable = 'off';
            app.DisplayMetadata.Text = 'Metadonnées';
            app.DisplayHelp = uimenu(app.AutresMenu);
            app.DisplayHelp.MenuSelectedFcn = createCallbackFcn(app, @DisplayHelpMenuSelected, true);
            app.DisplayHelp.Tooltip = {'Affiche le PDF d''utilisation de l''interface'};
            app.DisplayHelp.Text = 'Aide';
            app.DisplayAbout = uimenu(app.AutresMenu);
            app.DisplayAbout.MenuSelectedFcn = createCallbackFcn(app, @DisplayAboutMenuSelected, true);
            app.DisplayAbout.Tooltip = {'Affiche les informations de crédits de l''interface'};
            app.DisplayAbout.Text = 'Information';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ConfigPanel = uipanel(app.GridLayout);
            app.ProcessPanel = uipanel(app.ConfigPanel);
            app.ProcessPanel.Enable = 'off';
            app.ProcessPanel.Title = 'Traitements de base';
            app.ContrastStretchingButton = uibutton(app.ProcessPanel, 'state');
            app.ContrastStretchingButton.ValueChangedFcn = createCallbackFcn(app, @ContrastStretchingButtonValueChanged, true);
            app.ContrastStretchingButton.Text = 'Amélioration de contraste';
            app.PcaButton = uibutton(app.ProcessPanel, 'state');
            app.PcaButton.ValueChangedFcn = createCallbackFcn(app, @PcaButtonValueChanged, true);
            app.PcaButton.Text = 'Analyse en composantes principales';
            app.SpectralPointButton = uibutton(app.ProcessPanel, 'state');
            app.SpectralPointButton.ValueChangedFcn = createCallbackFcn(app, @SpectralPointButtonValueChanged, true);
            app.SpectralPointButton.Enable = 'off';
            app.SpectralPointButton.Text = 'Activer le pointage spectral';
            app.TrueColorsButton = uibutton(app.ProcessPanel, 'state');
            app.TrueColorsButton.ValueChangedFcn = createCallbackFcn(app, @TrueColorsButtonValueChanged, true);
            app.TrueColorsButton.Enable = 'off';
            app.TrueColorsButton.Text = 'Rendu vraies couleurs';
            app.UserInfoPanel = uipanel(app.ConfigPanel);
            app.UserInfoPanel.Title = 'Retour utilisateur';
            app.ConsoleArea = uitextarea(app.UserInfoPanel);
            app.ConsoleArea.Editable = 'off';
            app.ConsoleArea.Value = {'> Console d''état '; '> Veuillez charger des données.'; '> ....'};
            app.CorrectiondeloffsetLampLabel = uilabel(app.UserInfoPanel);
            app.CorrectiondeloffsetLampLabel.Text = 'Correction de l''offset';
            app.OffsetCalibLamp = uilamp(app.UserInfoPanel);
            app.CorrectionflatfieldLampLabel = uilabel(app.UserInfoPanel);
            app.CorrectionflatfieldLampLabel.Text = 'Correction flatfield';
            app.FlatfieldCalibLamp = uilamp(app.UserInfoPanel);
            app.MiseenregistreLampLabel = uilabel(app.UserInfoPanel);
            app.MiseenregistreLampLabel.Text = 'Mise en registre';
            app.RegisterCalibLamp = uilamp(app.UserInfoPanel);
            app.CalibrationcouleurspectreLampLabel = uilabel(app.UserInfoPanel);
            app.CalibrationcouleurspectreLampLabel.Text = 'Calibration couleur/spectre';
            app.ColorCalibLamp = uilamp(app.UserInfoPanel);
            app.DisplayTypePanel = uipanel(app.ConfigPanel);
            app.DisplayTypePanel.Enable = 'off';
            app.DisplayTypePanel.Title = 'Choix de l''affichage';
            app.ChoosenDisplayType = uitabgroup(app.DisplayTypePanel);
            app.ChoosenDisplayType.SelectionChangedFcn = createCallbackFcn(app, @ChoosenDisplayTypeSelectionChanged, true);
            app.GreyScaleTab = uitab(app.ChoosenDisplayType);
            app.GreyScaleTab.Title = 'Échelle gris';
            app.GreyScaleTab.Tag = 'GreyScaleTab';
            app.NumroduclichmultispectralSpinnerLabel = uilabel(app.GreyScaleTab);
            app.NumroduclichmultispectralSpinnerLabel.Text = {'Numéro du cliché '; 'multispectral'};
            app.FilterNbSpinner = uispinner(app.GreyScaleTab);
            app.FilterNbSpinner.Limits = [1 16];
            app.FilterNbSpinner.ValueChangedFcn = createCallbackFcn(app, @FilterNbSpinnerValueChanged, true);
            app.FilterNbSpinner.Value = 16;
            app.LongeurdondecentralenmEditFieldLabel = uilabel(app.GreyScaleTab);
            app.LongeurdondecentralenmEditFieldLabel.Text = 'Longeur d''onde centrale [nm]';
            app.FilterCenterEditField = uieditfield(app.GreyScaleTab, 'numeric');
            app.FilterCenterEditField.Limits = [0 1500];
            app.FilterCenterEditField.RoundFractionalValues = 'on';
            app.FilterCenterEditField.Editable = 'off';
            app.BandepassantenmEditFieldLabel = uilabel(app.GreyScaleTab);
            app.BandepassantenmEditFieldLabel.Text = 'Bande passante [nm]';
            app.FilterBwEditField = uieditfield(app.GreyScaleTab, 'numeric');
            app.FilterBwEditField.Limits = [0 200];
            app.FilterBwEditField.RoundFractionalValues = 'on';
            app.FilterBwEditField.Editable = 'off';
            app.NomdufiltreDropDownLabel = uilabel(app.GreyScaleTab);
            app.NomdufiltreDropDownLabel.Text = 'Nom du filtre';
            app.FilterNameDropDown = uidropdown(app.GreyScaleTab);
            app.FilterNameDropDown.Items = {'425AF30', '465AF30', '480DF17', '500DF20', '525DF20', '550DF20', '565BP10', '580DF19', '600BP20', '615DF20', '630DF22', '640DF20', '665DF15', '675DF15', '690AF20', 'None'};
            app.FilterNameDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterNameDropDownValueChanged, true);
            app.FilterNameDropDown.Value = 'None';
            app.NegativeImgButton = uibutton(app.GreyScaleTab, 'push');
            app.NegativeImgButton.ButtonPushedFcn = createCallbackFcn(app, @NegativeImgButtonPushed, true);
            app.NegativeImgButton.Text = 'Calcul du négatif';
            app.FalseColorTab = uitab(app.ChoosenDisplayType);
            app.FalseColorTab.Title = 'Fausses couleurs';
            app.CanalRDropDownLabel = uilabel(app.FalseColorTab);
            app.CanalRDropDownLabel.Text = 'Canal R';
            app.CanalRDropDown = uidropdown(app.FalseColorTab);
            app.CanalRDropDown.Items = {'425AF30', '465AF30', '480DF17', '500DF20', '525DF20', '550DF20', '565BP10', '580DF19', '600BP20', '615DF20', '630DF22', '640DF20', '665DF15', '675DF15', '690AF20', 'None'};
            app.CanalRDropDown.Value = 'None';
            app.CanalGDropDownLabel = uilabel(app.FalseColorTab);
            app.CanalGDropDownLabel.Text = 'Canal G';
            app.CanalGDropDown = uidropdown(app.FalseColorTab);
            app.CanalGDropDown.Items = {'425AF30', '465AF30', '480DF17', '500DF20', '525DF20', '550DF20', '565BP10', '580DF19', '600BP20', '615DF20', '630DF22', '640DF20', '665DF15', '675DF15', '690AF20', 'None'};
            app.CanalGDropDown.Value = 'None';
            app.CanalBDropDownLabel = uilabel(app.FalseColorTab);
            app.CanalBDropDownLabel.Text = 'Canal B';
            app.CanalBDropDown = uidropdown(app.FalseColorTab);
            app.CanalBDropDown.Items = {'425AF30', '465AF30', '480DF17', '500DF20', '525DF20', '550DF20', '565BP10', '580DF19', '600BP20', '615DF20', '630DF22', '640DF20', '665DF15', '675DF15', '690AF20', 'None'};
            app.CanalBDropDown.Value = 'None';
            app.ApplyFalseColorButton = uibutton(app.FalseColorTab, 'push');
            app.ApplyFalseColorButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFalseColorButtonPushed, true);
            app.ApplyFalseColorButton.Text = 'Appliquer';
            app.DisplayPanel = uipanel(app.GridLayout);
            app.ImageDetailAxes = uiaxes(app.DisplayPanel);
            title(app.ImageDetailAxes, 'Histogramme de l''image en cours')
            xlabel(app.ImageDetailAxes, 'Intensité (0-1)')
            ylabel(app.ImageDetailAxes, 'Occurences (Pixels)')
            zlabel(app.ImageDetailAxes, 'Z')
            app.ImageDetailAxes.XLim = [0 1];
            app.ImageDetailAxes.XTick = [0 0.25 0.5 0.75 1];
            app.ImageRendering = uiaxes(app.DisplayPanel);
            app.ImageRendering.XTick = [];
            app.ImageRendering.YTick = [];
            app.ImageRendering.ButtonDownFcn = createCallbackFcn(app, @ImageRenderingButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MultispectralViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end